classdef GUI_LocoD < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LocoDUIFigure             matlab.ui.Figure
        LoadSignalButton          matlab.ui.control.Button
        Image                     matlab.ui.control.Image
        PatternRecognitionButton  matlab.ui.control.Button
        RecordButton              matlab.ui.control.Button
    end

    properties (Access = private)
        AFE_app GUI_AFE;     % Analog front-end
        OffProcess GUI_OfflineProcess;
        recprops RecordingProperties;
        LoadSig_app LoadSignal;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Delete all other apps
            allfigs = findall(0, 'Type', 'figure');
            allfigs = allfigs(allfigs ~= app.LocoDUIFigure);
            if ~isempty(allfigs)
                answer = questdlg('LocoD will close everything else. Continue?', 'answer', 'yes', 'no', 'yes');
                if strcmp(answer, 'no')
                    % Don't continue
                    delete(app)
                    return
                end
            end
            delete(allfigs);

            % Delete all dangling timers
            delete(timerfindall);
        end

        % Button pushed function: RecordButton
        function RecordButtonPushed(app, event)
            % Open the AFE window, and let them know that I am the parent.
            app.AFE_app = GUI_AFE(app);

        end

        % Button pushed function: PatternRecognitionButton
        function PatternRecognitionButtonPushed(app, event)
           app.OffProcess=GUI_OfflineProcess();
        end

        % Button pushed function: LoadSignalButton
        function LoadSignalButtonPushed(app, event)
            app.LoadSig_app=LoadSignal();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LocoDUIFigure = uifigure('Visible', 'off');
            app.LocoDUIFigure.Name = 'LocoD';
            app.LocoDUIFigure.Icon = 'LocoD2.png';
            app.RecordButton = uibutton(app.LocoDUIFigure, 'push');
            app.RecordButton.ButtonPushedFcn = createCallbackFcn(app, @RecordButtonPushed, true);
            app.RecordButton.Text = 'Record';
            app.PatternRecognitionButton = uibutton(app.LocoDUIFigure, 'push');
            app.PatternRecognitionButton.ButtonPushedFcn = createCallbackFcn(app, @PatternRecognitionButtonPushed, true);
            app.PatternRecognitionButton.Text = 'Pattern Recognition';
            app.Image = uiimage(app.LocoDUIFigure);
            app.Image.ImageSource = 'LocoD2.png';
            app.LoadSignalButton = uibutton(app.LocoDUIFigure, 'push');
            app.LoadSignalButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSignalButtonPushed, true);
            app.LoadSignalButton.Text = 'Load Signal';
            % Show the figure after all components are created
            app.LocoDUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_LocoD

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LocoDUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LocoDUIFigure)
        end
    end
end