classdef GUI_OnlineProcess < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OnlineProcessingUIFigure      matlab.ui.Figure
        btnStart_2                    matlab.ui.control.Button
        btnConnect                    matlab.ui.control.Button
        lblAccu                       matlab.ui.control.Label
        lblTrueLabel                  matlab.ui.control.Label
        lblTrueTag                    matlab.ui.control.Label
        DebugLabel                    matlab.ui.control.Label
        SettingsPanel                 matlab.ui.container.Panel
        PostProcessingMethodDropDown  matlab.ui.control.DropDown
        PostProcessingMethodDropDownLabel  matlab.ui.control.Label
        ThresholdEditField            matlab.ui.control.EditField
        ThresholdEditFieldLabel       matlab.ui.control.Label
        pnlManualKeys                 matlab.ui.container.Panel
        StandStiffButton              matlab.ui.control.Button
        RampDescentButton             matlab.ui.control.Button
        RampAscentButton              matlab.ui.control.Button
        StairDescentButton            matlab.ui.control.Button
        StairAscentButton             matlab.ui.control.Button
        WalkingButton                 matlab.ui.control.Button
        StandupButton                 matlab.ui.control.Button
        SitdownButton                 matlab.ui.control.Button
        btnStart                      matlab.ui.control.Button
        btnResume                     matlab.ui.control.Button
        PredictedLabel                matlab.ui.control.Label
        PredictedTagLabel             matlab.ui.control.Label
        OSLPanel                      matlab.ui.container.Panel
        OSLPannelButton               matlab.ui.control.Button
        Graph                         matlab.ui.control.UIAxes
    end

    properties (Access = public)
        % TODO: Clean this damn mess :||||
        onlineProcessing OnlineProcessing
        oslgui GUI_OSL2
        OSL=0
        comm Communication
        OSLWalkMode = 0;
        transitionTo="";
        LPS=0;
        PrevLabelePredicted=LOCO.TDefault;

        MajorityVoteWindowNum;
        MajorVoteHistory = [];
        increment;
        GaitMode;
        OnlineMode=[];
        trueTags=[],

        transitionPoints=[];
        trueTagValue=LOCO.TDefault;

        % Graph information
        graphTrueTagsStem = [];
        graphPredLabelStair = [];
        graphPredNextLabelStair = [];

        tmrUpdateStatus timer;
    end

    properties (Access = private, Dependent)
        recprops RecordingProperties
        offlineProcessing Decoding;
        recsess  RecordingFunctions;
        signal Signal;
    end

    methods
        function p = get.recprops(app)
            p = app.onlineProcessing.onlineRecsess.GetRecProps();
        end

        function p = get.offlineProcessing(app)
            p = app.onlineProcessing.offlineProcessing;
        end

        function p = get.recsess(app)
            p = app.onlineProcessing.onlineRecsess;
        end

        function p = get.signal(app)
            p = app.onlineProcessing.onlineRecsess.signal;
        end
    end

    methods (Access = private)

        function onSignalClean(app, sig)
            if ~isvalid(app) || ~isvalid(app.Graph)
                return % SENTINEL
            end

            % Clear online processing information
            % TODO: Ensure if this is complete.
            app.LPS = 0;
            app.onlineProcessing.Reset();
            app.transitionPoints=[];
            app.trueTagValue=LOCO.TDefault;

            % Clear graph
            cla(app.Graph);
            app.graphTrueTagsStem = [];
            app.graphPredLabelStair = [];
            app.graphPredNextLabelStair = [];

            gprlog('Signal is cleared.');
        end

        function onAddGroundTruthTag(app, ~, tagInfo)
            if ~isvalid(app) || ~isvalid(app.Graph)
                return % SENTINEL
            end

            gprlog('Ground truth tag %0.03f -- %d', ...
                tagInfo(2), tagInfo(1));

            % Store the newly added tag, also display in GUI.
            tagValue = tagInfo(1);
            app.trueTagValue = tagValue;
            app.lblTrueTag.Text = num2str(tagValue);
            [app.lblTrueLabel.Text,~] = GetTagName(tagValue);
        end

        function AddNewGroundTruthTags(app, newTagValue)
            % Store the tagvalue in signal, in list of ground truth tags.
            % This will lead to a callback to onAddGroundTruthTag.
            t_moment=app.signal.GetTime();
            app.signal.AddGroundTruthTags(newTagValue,t_moment);
        end

        function PlotTagsAndLabels(app)
            % Initialize the graph first
            doInitGraph = isempty(app.graphTrueTagsStem) || ...
                isempty(app.graphPredLabelStair) || ...
                isempty(app.graphPredNextLabelStair);

            if doInitGraph
                cla(app.Graph);
                disableDefaultInteractivity(app.Graph);
                hold(app.Graph, 'off');
                app.graphPredNextLabelStair = stairs(app.Graph, 0, 0, 'color', 'cyan');
                hold(app.Graph, 'on');
                app.graphPredLabelStair = stairs(app.Graph, 0, 0, 'color', 'blue');
                hold(app.Graph, 'on');
                app.graphTrueTagsStem = stem(app.Graph, 0, 0, 'filled', 'Color','r');
            end

            % Insert data
            % TODO: Optimize graph
            FS = app.signal.recProps.SamplingFreq;

            % We draw a maximum of last 50 tags/labels.
            % We assume that tags and labels are up-to-date (there is no
            % information from future, after app.signal.GetTime()).
            maxCount = 50;

            cnt = size(app.signal.tags,2);
            if cnt >= 1
                endRange = cnt;
                startRange = cnt - maxCount + 1;
                if startRange < 1
                    startRange = 1;
                end
                app.graphTrueTagsStem.XData = app.signal.tags(2, startRange:endRange);
                app.graphTrueTagsStem.YData = app.signal.tags(1, startRange:endRange);
            end

            cnt = size(app.onlineProcessing.PredictedLabelAll,2);
            if cnt >= 1
                endRange = cnt;
                startRange = cnt - maxCount + 1;
                if startRange < 1
                    startRange = 1;
                end
                range = startRange:endRange;

                % Each predicted label that denotes a transition has form of
                %  (10*PrevLabel+CurrentLabel).
                % If there is no transition, it has a form of
                %  (CurrentLabel).
                predictions = app.onlineProcessing.PredictedLabelAll(1, range);
                prvLabel = floor(predictions / 10);
                curLabel = mod(predictions, 10);
                prvOrCurLabel = prvLabel + (prvLabel == 0) .* curLabel;

                app.graphPredLabelStair.XData = app.onlineProcessing.PredictedLabelAll(2, range) / FS;
                app.graphPredLabelStair.YData = prvOrCurLabel;

                app.graphPredNextLabelStair.XData = app.graphPredLabelStair.XData;
                app.graphPredNextLabelStair.YData = curLabel;
            end
            
            % Adjust axes limits
            app.Graph.XLim = [app.signal.GetTime() - 10, app.signal.GetTime()];
            %app.Graph.XLim = [0, app.signal.GetTime()];
            app.Graph.YLim = [2 10];
        end

        function onDataInCallback(app, sig, numNewSample)
            if ~isvalid(app) || ~isvalid(app.Graph)
                return % SENTINEL
            end

            % TODO:
            % app.MajorityVoteWindowNum=str2double(app.MajorityVoteEditField.Value);

            nTotalSample=app.signal.GetNumSample(); %Num all samples
            FS=app.signal.recProps.SamplingFreq;
            blkAfter =app.offlineProcessing.AfterEvent *app.recprops.SamplingFreq;
            blkBefore=app.offlineProcessing.BeforeEvent*app.recprops.SamplingFreq;
            app.DebugLabel.Text = sprintf("LPS%0.3fs, IN%0.3fs, TOT%0.3fs", ...
                app.LPS / FS, numNewSample / FS, ...
                nTotalSample / FS);

            % First decide what range of ever-incoming signal we want to
            % process. Goal is not to even look at the samples that don't
            % stand a chance to present a transition point.
            % Example is when a sample is a transition point,
            %   but there is not yet enough data available yet.

            % Start from after Last-Processed-Sampled
            procStart = app.LPS + 1;

            % We need at least one sample and at least blkAfter samples
            % after any gait transition point. blkAfter is definitely >= 1,
            % therefore:
            procEnd = nTotalSample - blkAfter - 1;

            % We need at least blkBefore-1 samples before any transition point.
            if procStart <= blkBefore - 1
                procStart = blkBefore - 1 + 1;
            end

            % Check if the range is not empty.
            if procStart > procEnd
                % No viable samples. Don't continue now.
                return
            end

            %  ! ! !
            % Already note down that next time we will start processing at
            % least from after procEnd. All sample in range
            % [procStart,procEnd] will be checked if they are a trans. point. We
            % don't have to reconsider in future.
            app.LPS = procEnd;

            % Look at all samples, find transition points.
            %gprlog("LPS %d -- PROC %d <-> %d", app.LPS, procStart, procEnd);
            for S = procStart : procEnd
                % Look at this sample and next one.
                wndPresSensState = sig.signal(app.recprops.IdxPS,S:S+1);
                wndMark = LOCO.TagT(wndPresSensState);

                if wndMark ~= LOCO.SwingToStanceMark && wndMark ~= LOCO.StanceToSwingMark
                    % This sample and next one don't constitue a gait phase
                    % transition.
                    continue
                end

                % We are going to extract a block around this point.
                blk = sig.signal(:, S-blkBefore+1:S+blkAfter);

                gprlog("%0.3f -- %d", S / FS, wndMark);

                % Append transition point
                % TODO: Avoid dynamic expansion
                app.transitionPoints = [app.transitionPoints, [wndMark; S]];
                
                if wndMark == LOCO.StanceToSwingMark
                    gaitPhase = "Swing";
                elseif wndMark == LOCO.SwingToStanceMark
                    gaitPhase = "Stance";
                else
                    gaitPhase = ""; % must not get here
                end

                if ~isempty(gaitPhase)
                    app.onlineProcessing.ProcessWindows(blk,gaitPhase,S);
                    app.PredictedTagLabel.Text = int2str(app.onlineProcessing.LabelePredicted);
                    [app.PredictedLabel.Text, ~] = GetTagName(app.onlineProcessing.LabelePredicted);
                    %%Move OSL
                end
            end

            % Plot....
            app.PlotTagsAndLabels();
        end

        function UpdateUIStatus(app)
            try
                % Update the appearance of the UI
                if app.recsess.IsConnected()
                    app.btnConnect.Text = "Disconnect";
                    app.btnStart.Enable = 1;
                    % app.btnStopAndAccuracy.Enable = 1;
                else
                    app.btnConnect.Text = "Connect";
                    app.btnStart.Enable = 0;
                    % app.btnStopAndAccuracy.Enable = 0;
                end

                if app.recsess.IsRecordingStarted()
                    app.btnStart.Text = "Stop";
                    app.btnResume.Enable = 1;

                    if app.recsess.RecordingStat
                        app.btnResume.Text = "Pause";
                    else
                        app.btnResume.Text = "Resume";
                    end
                else
                    app.btnStart.Text = "Start";
                    app.btnResume.Enable = 0;
                end
            catch Ex
                gprlog('* Error updating apperance of Online GUI\n%s', GetExceptionSummary(Ex))
                rethrow(Ex)
            end
        end

        function OnUpdateStatus(app, tmrObj, ~)
            if ~isvalid(app) || ~isvalid(app.OnlineProcessingUIFigure)
                stop(tmrObj);
                delete(tmrObj);
                if isvalid(app)
                    app.tmrUpdateStatus = timer.empty;
                end
                return
            end
            if ~isempty(app.recsess)
                app.UpdateUIStatus()
            end
        end

        function OnUpdateStatusError(app, tmrObj, ~)
            stop(tmrObj);
            delete(tmrObj);
            if isvalid(app)
                app.tmrUpdateStatus = timer.empty;
            end
        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, OnlineMode, onlineProcessing)
            app.tmrUpdateStatus = timer('Name', 'tmrUpdateStatus', ...
                'Period', 1.0, ...
                'StartDelay', 0, ...
                'TasksToExecute', inf, ...
                'ExecutionMode', 'fixedRate', ...
                'TimerFcn', @app.OnUpdateStatus, ...
                'ErrorFcn', @app.OnUpdateStatusError);
            start(app.tmrUpdateStatus);

            app.onlineProcessing=onlineProcessing;
            app.signal.onSignalCallback = @app.onDataInCallback;
            app.signal.onSignalClean = @app.onSignalClean;
            app.signal.onAddGroundTruthTag = @app.onAddGroundTruthTag;
            app.OnlineMode=OnlineMode;
            app.UpdateUIStatus();
                
           
        end

        % Button pushed function: btnConnect
        function btnConnectPushed(app, event)

            if ~app.recsess.IsConnected()
                app.recsess.ConnectToDevice();
            else
                app.recsess.Disconnect();
            end

            app.UpdateUIStatus();
        end

        % Button pushed function: btnStart
        function btnStartPushed(app, event)


            app.onlineProcessing.PostProcessingMethod=app.PostProcessingMethodDropDown.Value;
            app.onlineProcessing.PostProcessingThreshold=str2double(app.ThresholdEditField.Value);

            if app.recsess.IsRecordingStarted()
                % Stop recording
                app.recsess.StopRecording();
                app.UpdateUIStatus();


            else
                app.recsess.StartRecording();
                app.UpdateUIStatus();

                % Online processing intermediate vars are cleared in
                % onSignalClean.
            end

            app.UpdateUIStatus();
        end

        % Button pushed function: btnStart_2
        function SaveButtonPushed(app, event)
            
                % Calculate accuracy?!
                onlineaccu= app.onlineProcessing.OnlineAccuracy(app.signal.tags,app.transitionPoints);
                app.lblAccu.Text=num2str(onlineaccu*100);

                % !@! What the hell is this?!
                %save("PredictedLabeles.mat",app.onlineProcessing.PredictedLabelAll)
                app.recsess.signal.GaitTransitionsOnline=app.transitionPoints;
                app.recsess.signal.PredictedtagsOnline=app.onlineProcessing.PredictedLabelAll;
                app.recsess.signal.SaveData();
            
        end

        % Button pushed function: btnResume
        function btnResumePushed(app, event)
            if app.recsess.IsRecordingStarted()
                app.recsess.RecordingStat = ~app.recsess.RecordingStat;

                app.UpdateUIStatus();
            end
        end

        % Callback function
        function ConnecttoOSLButtonPushed(app, event)
            app.OSL=1;
            app.comm.address=app.AddressEditField.Value;
            app.comm.port=app.PortEditField.Value;
            try
                tcpconnection=app.comm.connect();
            catch exception
                disp(['Error: ' exception.message]);
            end
            
            app.lblOslState.Text="Connected";
        end

        % Callback function
        function StiffButtonPushed(app, event)
            
            StiffMessage = 1003000013;
            if app.OSL == 1
                try
                    % Send the stiffness message
               %     response = app.comm.LocoDWritetoOSL(StiffMessage);

                    % Check if the response indicates success
%                     if response == %//TODOOOOO
%                         disp('Stiffness operation successful.');
%                     else
%                         error('Stiffness operation failed.');
%                     end

                catch exception
                    % Handle any errors that occur during the operation
                    disp(['Error during stiffness operation: ' exception.message]);
                end
            else
                disp('OSL is not active.');
            end


        end

        % Callback function
        function WalkButtonPushed(app, event)
            
            WalkMessage = 1003000013;
            if app.OSL == 1
                try
                    % Send the stiffness message
%                     response = app.comm.LocoDWritetoOSL(WalkMessage);
% 
%                     % Check if the response indicates success
%                     if response == %//TODOOOOO
%                         disp('Stiffness operation successful.');
%                     else
%                         error('Stiffness operation failed.');
%                     end

                catch exception
                    % Handle any errors that occur during the operation
                    disp(['Error during stiffness operation: ' exception.message]);
                end
            else
                disp('OSL is not active.');
            end

        end

        % Button pushed function: StandupButton
        function StandupButtonPushed(app, event)
            app.AddNewGroundTruthTags(2);
        end

        % Button pushed function: SitdownButton
        function SitdownButtonPushed(app, event)
            app.AddNewGroundTruthTags(1);
        end

        % Button pushed function: StandStiffButton
        function StandStiffButtonPushed(app, event)
            app.AddNewGroundTruthTags(8);
        end

        % Button pushed function: WalkingButton
        function WalkingButtonPushed(app, event)
            app.AddNewGroundTruthTags(3);
        end

        % Button pushed function: StairDescentButton
        function StairDescentButtonPushed(app, event)
            app.AddNewGroundTruthTags(7);
        end

        % Button pushed function: StairAscentButton
        function StairAscentButtonPushed(app, event)
            app.AddNewGroundTruthTags(6);
        end

        % Button pushed function: RampDescentButton
        function RampDescentButtonPushed(app, event)
            app.AddNewGroundTruthTags(5);
        end

        % Button pushed function: RampAscentButton
        function RampAscentButtonPushed(app, event)
            app.AddNewGroundTruthTags(4);
        end

        % Key press function: OnlineProcessingUIFigure
        function OnlineProcessingUIFigureKeyPress(app, event)
            key = event.Key;
            switch key
                case 'numpad1'
                    app.AddNewGroundTruthTags(1);

                case 'numpad2'
                    app.AddNewGroundTruthTags(2);

                case 'numpad3'
                    app.AddNewGroundTruthTags(3);

                case 'numpad4'
                    app.AddNewGroundTruthTags(4);

                case 'numpad5'
                    app.AddNewGroundTruthTags(5);

                case 'numpad6'
                    app.AddNewGroundTruthTags(6);

                case 'numpad7'
                    app.AddNewGroundTruthTags(7);

                case 'numpad8'
                    app.AddNewGroundTruthTags(8);
                    
                case 'add'  %Pause
                    if app.recsess.IsRecordingStarted()
                        app.recsess.RecordingStat=~app.recsess.RecordingStat;
                        app.UpdateUIStatus();
                    end

                    %case 'return' %Start
                    %    app.signal.onSignalCallback = @app.onDataInCallback;
                    %    app.recsess.StartRecording();
                    %case 'subtract' % Stop and save
                    %    app.recsess.StopRecording();
                    %    app.signal.SaveData();
            end
        end

        % Callback function
        function chkClassificationValueChanged(app, event)
            value = app.chkClassification.Value;
            if value==1
                app.RampAscentButton.Enable=1;
                app.RampDescentButton.Enable=1;
                app.StairAscentButton.Enable=1;
                app.StairDescentButton.Enable=1;
                app.StandupButton.Enable=1;
                app.SitdownButton.Enable=1;
                app.WalkingButton.Enable=1;
                app.StandStiffButton.Enable=1;
            else
                app.RampAscentButton.Enable=0;
                app.RampDescentButton.Enable=0;
                app.StairAscentButton.Enable=0;
                app.StairDescentButton.Enable=0;
                app.StandupButton.Enable=0;
                app.SitdownButton.Enable=0;
                app.WalkingButton.Enable=0;
                app.StandStiffButton.Enable=0;
            end


        end

        % Close request function: OnlineProcessingUIFigure
        function OnlineProcessingUIFigureCloseRequest(app, event)
            % Cleanup
            if isvalid(app.tmrUpdateStatus)
                stop(app.tmrUpdateStatus);
            end
            delete(app.tmrUpdateStatus);
            app.tmrUpdateStatus = timer.empty;
            app.recsess.StopRecording();
            app.recsess.Disconnect();

            % Ensure that we disconnect the callbacks
            app.recsess.signal.onSignalCallback = function_handle.empty();
            app.recsess.signal.onSignalClean = function_handle.empty();
            app.recsess.signal.onAddGroundTruthTag = function_handle.empty();

            % !@! WASBUGGY: Must delete app after all previous stuff.
            delete(app);
        end

        % Button pushed function: OSLPannelButton
        function OSLPannelButtonPushed(app, event)
            app.oslgui=GUI_OSL2();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.OnlineProcessingUIFigure = uifigure('Visible', 'off');
            app.OnlineProcessingUIFigure.Name = 'Online Processing';
            app.OnlineProcessingUIFigure.CloseRequestFcn = createCallbackFcn(app, @OnlineProcessingUIFigureCloseRequest, true);
            app.OnlineProcessingUIFigure.KeyPressFcn = createCallbackFcn(app, @OnlineProcessingUIFigureKeyPress, true);
            app.Graph = uiaxes(app.OnlineProcessingUIFigure);
            app.OSLPanel = uipanel(app.OnlineProcessingUIFigure);
            app.OSLPanel.Title = 'OSL';
            app.OSLPannelButton = uibutton(app.OSLPanel, 'push');
            app.OSLPannelButton.ButtonPushedFcn = createCallbackFcn(app, @OSLPannelButtonPushed, true);
            app.OSLPannelButton.Text = 'OSL Pannel';
            app.PredictedTagLabel = uilabel(app.OnlineProcessingUIFigure);
            app.PredictedTagLabel.Text = 'Predicted Tag';
            app.PredictedLabel = uilabel(app.OnlineProcessingUIFigure);
            app.PredictedLabel.Text = 'Predicted Label';
            app.btnResume = uibutton(app.OnlineProcessingUIFigure, 'push');
            app.btnResume.ButtonPushedFcn = createCallbackFcn(app, @btnResumePushed, true);
            app.btnResume.Text = 'Resume/Pause';
            app.btnStart = uibutton(app.OnlineProcessingUIFigure, 'push');
            app.btnStart.ButtonPushedFcn = createCallbackFcn(app, @btnStartPushed, true);
            app.btnStart.Text = 'Start/Stop';
            app.pnlManualKeys = uipanel(app.OnlineProcessingUIFigure);
            app.pnlManualKeys.Title = 'Add ground truth tags';
            app.SitdownButton = uibutton(app.pnlManualKeys, 'push');
            app.SitdownButton.ButtonPushedFcn = createCallbackFcn(app, @SitdownButtonPushed, true);
            app.SitdownButton.Text = 'Sit down';
            app.StandupButton = uibutton(app.pnlManualKeys, 'push');
            app.StandupButton.ButtonPushedFcn = createCallbackFcn(app, @StandupButtonPushed, true);
            app.StandupButton.Text = 'Stand up';
            app.WalkingButton = uibutton(app.pnlManualKeys, 'push');
            app.WalkingButton.ButtonPushedFcn = createCallbackFcn(app, @WalkingButtonPushed, true);
            app.WalkingButton.Text = 'Walking';
            app.StairAscentButton = uibutton(app.pnlManualKeys, 'push');
            app.StairAscentButton.ButtonPushedFcn = createCallbackFcn(app, @StairAscentButtonPushed, true);
            app.StairAscentButton.Text = 'Stair Ascent';
            app.StairDescentButton = uibutton(app.pnlManualKeys, 'push');
            app.StairDescentButton.ButtonPushedFcn = createCallbackFcn(app, @StairDescentButtonPushed, true);
            app.StairDescentButton.Text = 'Stair Descent';
            app.RampAscentButton = uibutton(app.pnlManualKeys, 'push');
            app.RampAscentButton.ButtonPushedFcn = createCallbackFcn(app, @RampAscentButtonPushed, true);
            app.RampAscentButton.Text = 'Ramp Ascent';
            app.RampDescentButton = uibutton(app.pnlManualKeys, 'push');
            app.RampDescentButton.ButtonPushedFcn = createCallbackFcn(app, @RampDescentButtonPushed, true);
            app.RampDescentButton.Text = 'Ramp Descent';
            app.StandStiffButton = uibutton(app.pnlManualKeys, 'push');
            app.StandStiffButton.ButtonPushedFcn = createCallbackFcn(app, @StandStiffButtonPushed, true);
            app.StandStiffButton.Text = 'Stand/Stiff';
            app.SettingsPanel = uipanel(app.OnlineProcessingUIFigure);
            app.SettingsPanel.Title = 'Settings';
            app.ThresholdEditFieldLabel = uilabel(app.SettingsPanel);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.SettingsPanel, 'text');
            app.PostProcessingMethodDropDownLabel = uilabel(app.SettingsPanel);
            app.PostProcessingMethodDropDownLabel.Text = 'PostProcessing Method';
            app.PostProcessingMethodDropDown = uidropdown(app.SettingsPanel);
            app.PostProcessingMethodDropDown.Items = {'MajorityVote', 'ProbabilityBasedMajority', 'ProbabilityBasedMax', 'ProbabilityBasedMeanMax', 'MeanMax', 'MeanMaxProbabilityBased'};
            app.PostProcessingMethodDropDown.Value = 'MajorityVote';
            app.DebugLabel = uilabel(app.OnlineProcessingUIFigure);
            app.DebugLabel.Text = 'Debug';
            app.lblTrueTag = uilabel(app.OnlineProcessingUIFigure);
            app.lblTrueTag.Text = 'True Tag';
            app.lblTrueLabel = uilabel(app.OnlineProcessingUIFigure);
            app.lblTrueLabel.Text = 'True Label';
            app.lblAccu = uilabel(app.OnlineProcessingUIFigure);
            app.lblAccu.Text = 'Accuracy will be displayed here.';
            app.btnConnect = uibutton(app.OnlineProcessingUIFigure, 'push');
            app.btnConnect.ButtonPushedFcn = createCallbackFcn(app, @btnConnectPushed, true);
            app.btnConnect.Text = 'Conn/Disconn';
            app.btnStart_2 = uibutton(app.OnlineProcessingUIFigure, 'push');
            app.btnStart_2.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.btnStart_2.Text = 'Save';
            % Show the figure after all components are created
            app.OnlineProcessingUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_OnlineProcess(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.OnlineProcessingUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OnlineProcessingUIFigure)
        end
    end
end