classdef GUI_OfflineProcess < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OfflineProcessingUIFigure      matlab.ui.Figure
        DataNameLabel                  matlab.ui.control.Label
        CleanbadroundsCheckBox         matlab.ui.control.CheckBox
        FeaturesPanel                  matlab.ui.container.Panel
        TabGroup                       matlab.ui.container.TabGroup
        EMGTab_Feature                 matlab.ui.container.Tab
        btnEMGHungPaper                matlab.ui.control.Button
        lstEMGFeatures                 matlab.ui.control.ListBox
        btnEMGTop4                     matlab.ui.control.Button
        IMUPSTab_2                     matlab.ui.container.Tab
        btnIMUTop4                     matlab.ui.control.Button
        lstIMUFeature                  matlab.ui.control.ListBox
        PlotButton                     matlab.ui.control.Button
        lblExtractFeat                 matlab.ui.control.Label
        ExtractFeaturesButton          matlab.ui.control.Button
        ClassificationPanel            matlab.ui.container.Panel
        LoadClassifierButton           matlab.ui.control.Button
        SaveClasifierButton            matlab.ui.control.Button
        SavetagnamesButton             matlab.ui.control.Button
        ValidationDropDown             matlab.ui.control.DropDown
        ValidationDropDownLabel        matlab.ui.control.Label
        ArchitectureDropDown           matlab.ui.control.DropDown
        ArchitectureDropDownLabel      matlab.ui.control.Label
        lblOfflineProc                 matlab.ui.control.Label
        ClassifierTypeDropDown         matlab.ui.control.DropDown
        ClassifierTypeDropDownLabel    matlab.ui.control.Label
        OfflineProcessingButton        matlab.ui.control.Button
        ProcessingMethodDropDown       matlab.ui.control.DropDown
        ProcessingMethodDropDownLabel  matlab.ui.control.Label
        lblLoaded                      matlab.ui.control.Label
        TreatSignalPanel               matlab.ui.container.Panel
        lblChannelSummary              matlab.ui.control.Label
        btnSelectSubset                matlab.ui.control.Button
        lstChannels                    matlab.ui.control.ListBox
        lblTreatSig                    matlab.ui.control.Label
        TreatSignalButton              matlab.ui.control.Button
        TabGroup2                      matlab.ui.container.TabGroup
        EMGTab_2                       matlab.ui.container.Tab
        FilteringforEMGPanel           matlab.ui.container.Panel
        FilterListBox                  matlab.ui.control.ListBox
        FilterListBox_3Label           matlab.ui.control.Label
        EditField                      matlab.ui.control.NumericEditField
        EditField_3Label               matlab.ui.control.Label
        ChannelEditField               matlab.ui.control.NumericEditField
        ChannelEditField_3Label        matlab.ui.control.Label
        WindowingPanel                 matlab.ui.container.Panel
        GaitphasesforwindowingListBox  matlab.ui.control.ListBox
        GaitphasesforwindowingListBoxLabel  matlab.ui.control.Label
        WindowingmethodDropDown        matlab.ui.control.DropDown
        WindowingmethodDropDownLabel   matlab.ui.control.Label
        ValidEditField                 matlab.ui.control.NumericEditField
        ValidEditField_3Label          matlab.ui.control.Label
        TestEditField                  matlab.ui.control.NumericEditField
        TestEditField_3Label           matlab.ui.control.Label
        TrainEditField                 matlab.ui.control.NumericEditField
        TrainEditField_3Label          matlab.ui.control.Label
        windowlengthsEditField         matlab.ui.control.NumericEditField
        windowlengthsEditFieldLabel    matlab.ui.control.Label
        AftereventsEditField           matlab.ui.control.NumericEditField
        AftereventsEditField_3Label    matlab.ui.control.Label
        IncrementtimesEditField        matlab.ui.control.NumericEditField
        IncrementtimesEditFieldLabel   matlab.ui.control.Label
        BeforeeventsEditField          matlab.ui.control.NumericEditField
        BeforeeventsEditField_3Label   matlab.ui.control.Label
        IMUPSTab                       matlab.ui.container.Tab
        FilteringPanel_2               matlab.ui.container.Panel
        FilterListBox_2                matlab.ui.control.ListBox
        FilterListBox_2Label           matlab.ui.control.Label
        EditField_2                    matlab.ui.control.NumericEditField
        EditField_2Label               matlab.ui.control.Label
        ChannelEditField_2             matlab.ui.control.NumericEditField
        ChannelEditField_2Label        matlab.ui.control.Label
        LoadButton                     matlab.ui.control.Button
    end


    properties (Access = public)
        recProps RecordingProperties
        sigData Signal;
        processing Decoding;
    end

    properties (Dependent, Access = private)
        % Example of propert getter
        numAllCh
        numEMGCh
        numIMUCh
        %IMUchanName
        sf
        % TODO: See get.numCh and get.sf below.
        % TODO: Add more.
    end


    methods
        function x = get.numAllCh(app)
            x = app.recProps.NumAllCh;
        end
        function x = get.numEMGCh(app)
            x = app.recProps.NumEMGCh;
        end
        function x = get.numIMUCh(app)
            x = app.recProps.NumIMUCh;
        end
        %function x = get.IMUchanName(app)
        %    x = app.recProps.IMU_ChannelNames;
        %end
        function x = get.sf(app)
            x = app.recProps.SamplingFreq;
        end
    end

    methods (Access = private)

        function UpdateChannelList(app)
            app.lstChannels.Items = {};

            if isempty(app.recProps)
                return
            end

            cnt = 1;
            items = cell(1, app.recProps.NumAllCh);

            cntGroupEMG = 0;
            for ch = app.recProps.IdxEMG
                cntGroupEMG = cntGroupEMG + 1;
                items{cnt} = sprintf('Ch #%d, EMG %d', ch, cntGroupEMG);
                cnt = cnt + 1;
            end

            cntGroupPS = 0;
            for ch = app.recProps.IdxPS
                cntGroupPS = cntGroupPS + 1;
                items{cnt} = sprintf('Ch #%d, PS %d', ch, cntGroupPS);
                cnt = cnt + 1;
            end

            cntGroupIMU = 0;
            for ch = app.recProps.IdxIMU
                cntGroupIMU = cntGroupIMU + 1;
                items{cnt} = sprintf('Ch #%d, IMU %d', ch, cntGroupIMU);
                cnt = cnt + 1;
            end

            app.lstChannels.Items = items;

            app.lblChannelSummary.Text = sprintf("Total channels in recording:\n" + ...
                "%d EMG, %d PS, %d IMU\n\nSelected subset:\n" + ...
                "%d EMG, %d PS, %d IMU", ...
                app.recProps.UnmaskedNumEMGCh, app.recProps.UnmaskedNumPSCh, app.recProps.UnmaskedNumIMUCh, ...
                app.recProps.NumEMGCh, app.recProps.NumPSCh, app.recProps.NumIMUCh);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            app.FilterListBox.Multiselect=1;

            % Already take top-4 features
            app.btnEMGTop4ButtonPushed(0)
            app.btnIMUTop4ButtonPushed(0)
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            %Reset apperance
            app.lblLoaded.Visible=0;
            app.lblTreatSig.Visible=0;
            app.lblExtractFeat.Visible=0;
            app.lblOfflineProc.Visible=0;
            app.TreatSignalButton.Enable=0;
            app.ExtractFeaturesButton.Enable=0;
            app.PlotButton.Enable=0;
            app.OfflineProcessingButton.Enable=0;
            

            % Load signal
            app.sigData= Signal.LoadSignal();


            app.DataNameLabel.Text=app.sigData.filename;
            app.recProps=app.sigData.recProps;

            % Create a processor class
            app.processing=Decoding();
            app.processing.originalRecProps = app.recProps;

            % Show list of all unmasked channels
            app.UpdateChannelList();

            % Modify appearance
            figure(app.OfflineProcessingUIFigure)
            app.lblLoaded.Visible=1;
            app.TreatSignalButton.Enable=1;


        end

        % Button pushed function: TreatSignalButton
        function TreatSignalButtonPushed(app, event)
            % Set process flag for each channel group if that group is
            % existing.
            % These flags may be removed later
            app.processing.IfProcessEMG = app.recProps.HasEMG;
            app.processing.IfProcessPS = app.recProps.HasPS;
            app.processing.IfProcessIMU = app.recProps.HasIMU;

            app.processing.FilterOutBadRounds = app.CleanbadroundsCheckBox.Value~=false;
            app.processing.FilterType=app.FilterListBox.Value;
            app.processing.IMUFilterType=cellstr(app.FilterListBox_2.Value);
            app.processing.BeforeEvent=app.BeforeeventsEditField.Value;
            app.processing.AfterEvent=app.AftereventsEditField.Value;
            app.processing.TrainPercent=app.TrainEditField.Value;
            app.processing.TestPercent=app.TestEditField.Value;
            app.processing.ValidPercent=app.ValidEditField.Value;
            app.processing.IncTime=app.IncrementtimesEditField.Value;
            app.processing.WindowLength=app.windowlengthsEditField.Value;
            app.processing.WindowingMethod=app.WindowingmethodDropDown.Value;

            app.processing.TreatSignal(...
                app.sigData.signal, ...
                app.sigData.originalPressureSignal, ...
                app.sigData.tags);
            app.lblTreatSig.Visible=1;
            app.ExtractFeaturesButton.Enable=1;
        end

        % Callback function
        function chkEMGTop4ValueChanged(app, event)
            value = app.chkEMGTop4.Value;
            if value==1
                
            end

        end

        % Button pushed function: ExtractFeaturesButton
        function ExtractFeaturesButtonPushed(app, event)
            app.processing.ClearFeatureExtraction();

            if isempty(app.lstEMGFeatures.Value) ==1
                app.processing.FetureSet = [];
                app.processing.IfProcessEMG = 0;
            else
                app.processing.FetureSet = string(app.lstEMGFeatures.Value);
            end

            if isempty(app.lstIMUFeature.Value) ==1
                app.processing.IMUFetureSet=[];
                app.processing.IfProcessIMU = 0;
                app.processing.IfProcessPS = 0;
            else
                app.processing.IMUFetureSet= string(app.lstIMUFeature.Value);
            end
            
            if app.processing.IfProcessEMG == 1
                app.processing.GetFeaturesEMG()
            end

            if app.processing.IfProcessIMU ==1
                app.processing.GetFeaturesIMU()

            end
            if app.processing.IfProcessPS == 1
                app.processing.GetFeaturesPS()
            end

            app.lblExtractFeat.Visible=1;
            app.PlotButton.Enable=1;
            app.OfflineProcessingButton.Enable=1;
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            %PlotDataFeaturespace(app.processing.features_all,app.processing.Lables_all)  % To be modifiable
            PlotDataFeaturespace([app.processing.features_SW_all,app.processing.IMUfeatures_SW_all,app.processing.PSfeatures_SW_all],app.processing.Lables_SW_all)  % To be modifiable

        end

        % Button pushed function: OfflineProcessingButton
        function OfflineProcessingButtonPushed(app, event)

            app.processing.ClassificationType=app.ClassifierTypeDropDown.Value;
            app.processing.ClassificationMethod=app.ProcessingMethodDropDown.Value;
            app.processing.ClassifierArchitecture=app.ArchitectureDropDown.Value;
            app.processing.ClassifireValidation=app.ValidationDropDown.Value;
            app.processing.OfflineClassify()
            app.lblOfflineProc.Visible=1;
   
        end

        % Button pushed function: SavetagnamesButton
        function SavetagnamesButtonPushed(app, event)
            app.processing.SaveTagNames();
        end

        % Callback function
        function HungpaperCheckBoxValueChanged(app, event)
            value = app.HungpaperCheckBox.Value;
            if value==1
                
            end
        end

        % Button pushed function: SaveClasifierButton
        function SaveClasifierButtonPushed(app, event)
            %app.processing.SaveClassifiers();
        end

        % Button pushed function: LoadClassifierButton
        function LoadClassifierButtonPushed(app, event)
            %app.processing.LoadClassifiers();
        end

        % Button pushed function: btnSelectSubset
        function btnSelectSubsetButtonPushed(app, event)
            % Open channel subset selector
            subapp = GUI_ChannelMask(app.recProps);
            subapp.ChannelMaskUIFigure.DeleteFcn = @(subapp,event) UpdateChannelList(app);
        end

        % Button pushed function: btnIMUTop4
        function btnIMUTop4ButtonPushed(app, event)
            app.lstIMUFeature.Value = ["tmn",'tmax' ,'tmin', 'tstd' ];
        end

        % Button pushed function: btnEMGTop4
        function btnEMGTop4ButtonPushed(app, event)
            app.lstEMGFeatures.Value= ["tmabs",'twl' ,'tzc', 'tslpch2' ];
        end

        % Button pushed function: btnEMGHungPaper
        function btnEMGHungPaperButtonPushed(app, event)
            app.lstEMGFeatures.Value= ["tmabs",'twl' ,'tzc', 'tslpch2','tareg' ];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.OfflineProcessingUIFigure = uifigure('Visible', 'off');
            app.OfflineProcessingUIFigure.Name = 'Offline Processing';
            app.LoadButton = uibutton(app.OfflineProcessingUIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = '0. Load';
            app.TreatSignalPanel = uipanel(app.OfflineProcessingUIFigure);
            app.TreatSignalPanel.Title = 'Treat Signal';
            app.TabGroup2 = uitabgroup(app.TreatSignalPanel);
            app.EMGTab_2 = uitab(app.TabGroup2);
            app.EMGTab_2.Title = 'EMG';
            app.WindowingPanel = uipanel(app.EMGTab_2);
            app.WindowingPanel.Title = 'Windowing';
            app.BeforeeventsEditField_3Label = uilabel(app.WindowingPanel);
            app.BeforeeventsEditField_3Label.Text = 'Before event(s)';
            app.BeforeeventsEditField = uieditfield(app.WindowingPanel, 'numeric');
            app.BeforeeventsEditField.Value = 0.2;
            app.IncrementtimesEditFieldLabel = uilabel(app.WindowingPanel);
            app.IncrementtimesEditFieldLabel.Text = 'Increment time (s)';
            app.IncrementtimesEditField = uieditfield(app.WindowingPanel, 'numeric');
            app.IncrementtimesEditField.Value = 0.03;
            app.AftereventsEditField_3Label = uilabel(app.WindowingPanel);
            app.AftereventsEditField_3Label.Text = 'After event(s)';
            app.AftereventsEditField = uieditfield(app.WindowingPanel, 'numeric');
            app.AftereventsEditField.Value = 0.11;
            app.windowlengthsEditFieldLabel = uilabel(app.WindowingPanel);
            app.windowlengthsEditFieldLabel.Text = '1 window length(s)';
            app.windowlengthsEditField = uieditfield(app.WindowingPanel, 'numeric');
            app.windowlengthsEditField.Value = 0.2;
            app.TrainEditField_3Label = uilabel(app.WindowingPanel);
            app.TrainEditField_3Label.Text = 'Train %';
            app.TrainEditField = uieditfield(app.WindowingPanel, 'numeric');
            app.TrainEditField.Value = 0.8;
            app.TestEditField_3Label = uilabel(app.WindowingPanel);
            app.TestEditField_3Label.Text = 'Test %';
            app.TestEditField = uieditfield(app.WindowingPanel, 'numeric');
            app.TestEditField.Value = 0.2;
            app.ValidEditField_3Label = uilabel(app.WindowingPanel);
            app.ValidEditField_3Label.Text = 'Valid %';
            app.ValidEditField = uieditfield(app.WindowingPanel, 'numeric');
            app.WindowingmethodDropDownLabel = uilabel(app.WindowingPanel);
            app.WindowingmethodDropDownLabel.Text = 'Windowing method';
            app.WindowingmethodDropDown = uidropdown(app.WindowingPanel);
            app.WindowingmethodDropDown.Items = {'On gait phase(before and after)', 'Window before and window after', 'Consecuitive'};
            app.WindowingmethodDropDown.Value = 'On gait phase(before and after)';
            app.GaitphasesforwindowingListBoxLabel = uilabel(app.WindowingPanel);
            app.GaitphasesforwindowingListBoxLabel.Enable = 'off';
            app.GaitphasesforwindowingListBoxLabel.Text = 'Gait phases for windowing';
            app.GaitphasesforwindowingListBox = uilistbox(app.WindowingPanel);
            app.GaitphasesforwindowingListBox.Items = {'Heel Contact', 'Toe off', 'Mid swing', 'Double stance', ''};
            app.GaitphasesforwindowingListBox.Enable = 'off';
            app.GaitphasesforwindowingListBox.Value = 'Heel Contact';
            app.FilteringforEMGPanel = uipanel(app.EMGTab_2);
            app.FilteringforEMGPanel.Title = 'Filtering for EMG';
            app.ChannelEditField_3Label = uilabel(app.FilteringforEMGPanel);
            app.ChannelEditField_3Label.Visible = 'off';
            app.ChannelEditField_3Label.Text = 'Channel';
            app.ChannelEditField = uieditfield(app.FilteringforEMGPanel, 'numeric');
            app.ChannelEditField.Visible = 'off';
            app.EditField_3Label = uilabel(app.FilteringforEMGPanel);
            app.EditField_3Label.Visible = 'off';
            app.EditField_3Label.Text = '-';
            app.EditField = uieditfield(app.FilteringforEMGPanel, 'numeric');
            app.EditField.Visible = 'off';
            app.FilterListBox_3Label = uilabel(app.FilteringforEMGPanel);
            app.FilterListBox_3Label.Text = 'Filter';
            app.FilterListBox = uilistbox(app.FilteringforEMGPanel);
            app.FilterListBox.Items = {'None', 'Notch 50 HZ', '20- 500 HZ'};
            app.FilterListBox.Value = 'None';
            app.IMUPSTab = uitab(app.TabGroup2);
            app.IMUPSTab.Title = 'IMU/PS';
            app.FilteringPanel_2 = uipanel(app.IMUPSTab);
            app.FilteringPanel_2.Title = 'Filtering';
            app.ChannelEditField_2Label = uilabel(app.FilteringPanel_2);
            app.ChannelEditField_2Label.Visible = 'off';
            app.ChannelEditField_2Label.Text = 'Channel';
            app.ChannelEditField_2 = uieditfield(app.FilteringPanel_2, 'numeric');
            app.ChannelEditField_2.Visible = 'off';
            app.EditField_2Label = uilabel(app.FilteringPanel_2);
            app.EditField_2Label.Visible = 'off';
            app.EditField_2Label.Text = '-';
            app.EditField_2 = uieditfield(app.FilteringPanel_2, 'numeric');
            app.EditField_2.Visible = 'off';
            app.FilterListBox_2Label = uilabel(app.FilteringPanel_2);
            app.FilterListBox_2Label.Text = 'Filter';
            app.FilterListBox_2 = uilistbox(app.FilteringPanel_2);
            app.FilterListBox_2.Items = {'None', '0- 50 HZ'};
            app.FilterListBox_2.Value = 'None';
            app.TreatSignalButton = uibutton(app.TreatSignalPanel, 'push');
            app.TreatSignalButton.ButtonPushedFcn = createCallbackFcn(app, @TreatSignalButtonPushed, true);
            app.TreatSignalButton.Enable = 'off';
            app.TreatSignalButton.Text = '1. Treat Signal';
            app.lblTreatSig = uilabel(app.TreatSignalPanel);
            app.lblTreatSig.Visible = 'off';
            app.lblTreatSig.Text = '✅';
            app.lstChannels = uilistbox(app.TreatSignalPanel);
            app.lstChannels.Items = {};
            app.lstChannels.Multiselect = 'on';
            app.lstChannels.Value = {};
            app.btnSelectSubset = uibutton(app.TreatSignalPanel, 'push');
            app.btnSelectSubset.ButtonPushedFcn = createCallbackFcn(app, @btnSelectSubsetButtonPushed, true);
            app.btnSelectSubset.Text = 'Select a subset of channels (a.k.a. channel mask)';
            app.lblChannelSummary = uilabel(app.TreatSignalPanel);
            app.lblLoaded = uilabel(app.OfflineProcessingUIFigure);
            app.lblLoaded.Visible = 'off';
            app.lblLoaded.Text = '✅';
            app.ClassificationPanel = uipanel(app.OfflineProcessingUIFigure);
            app.ClassificationPanel.Title = 'Classification';
            app.ProcessingMethodDropDownLabel = uilabel(app.ClassificationPanel);
            app.ProcessingMethodDropDownLabel.Text = 'Processing Method';
            app.ProcessingMethodDropDown = uidropdown(app.ClassificationPanel);
            app.ProcessingMethodDropDown.Items = {'LDA', 'SVM', 'DBN', 'Neural Network'};
            app.ProcessingMethodDropDown.Value = 'LDA';
            app.OfflineProcessingButton = uibutton(app.ClassificationPanel, 'push');
            app.OfflineProcessingButton.ButtonPushedFcn = createCallbackFcn(app, @OfflineProcessingButtonPushed, true);
            app.OfflineProcessingButton.Enable = 'off';
            app.OfflineProcessingButton.Text = '3. Offline Processing';
            app.ClassifierTypeDropDownLabel = uilabel(app.ClassificationPanel);
            app.ClassifierTypeDropDownLabel.Text = 'Classifier Type';
            app.ClassifierTypeDropDown = uidropdown(app.ClassificationPanel);
            app.ClassifierTypeDropDown.Items = {'', 'PseudoLinear', 'Linear'};
            app.ClassifierTypeDropDown.Value = 'PseudoLinear';
            app.lblOfflineProc = uilabel(app.ClassificationPanel);
            app.lblOfflineProc.Visible = 'off';
            app.lblOfflineProc.Text = '✅';
            app.ArchitectureDropDownLabel = uilabel(app.ClassificationPanel);
            app.ArchitectureDropDownLabel.Text = 'Architecture';
            app.ArchitectureDropDown = uidropdown(app.ClassificationPanel);
            app.ArchitectureDropDown.Items = {'Consecuitive', 'Binary', 'Mode-Specific', 'All data-Phase dependant', 'Mode-Specific-PhaseDependant'};
            app.ArchitectureDropDown.Value = 'Mode-Specific-PhaseDependant';
            app.ValidationDropDownLabel = uilabel(app.ClassificationPanel);
            app.ValidationDropDownLabel.Text = 'Validation';
            app.ValidationDropDown = uidropdown(app.ClassificationPanel);
            app.ValidationDropDown.Items = {'LOOCV', '10Fold', 'RondValidation', ''};
            app.ValidationDropDown.Value = '';
            app.SavetagnamesButton = uibutton(app.ClassificationPanel, 'push');
            app.SavetagnamesButton.ButtonPushedFcn = createCallbackFcn(app, @SavetagnamesButtonPushed, true);
            app.SavetagnamesButton.Text = 'Save tag names';
            app.SaveClasifierButton = uibutton(app.ClassificationPanel, 'push');
            app.SaveClasifierButton.ButtonPushedFcn = createCallbackFcn(app, @SaveClasifierButtonPushed, true);
            app.SaveClasifierButton.Text = 'Save Clasifier';
            app.LoadClassifierButton = uibutton(app.ClassificationPanel, 'push');
            app.LoadClassifierButton.ButtonPushedFcn = createCallbackFcn(app, @LoadClassifierButtonPushed, true);
            app.LoadClassifierButton.Text = 'Load Classifier';
            app.FeaturesPanel = uipanel(app.OfflineProcessingUIFigure);
            app.FeaturesPanel.Title = 'Features';
            app.ExtractFeaturesButton = uibutton(app.FeaturesPanel, 'push');
            app.ExtractFeaturesButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractFeaturesButtonPushed, true);
            app.ExtractFeaturesButton.Enable = 'off';
            app.ExtractFeaturesButton.Text = '2. Extract Features';
            app.lblExtractFeat = uilabel(app.FeaturesPanel);
            app.lblExtractFeat.Visible = 'off';
            app.lblExtractFeat.Text = '✅';
            app.PlotButton = uibutton(app.FeaturesPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Enable = 'off';
            app.PlotButton.Text = 'Plot';
            app.TabGroup = uitabgroup(app.FeaturesPanel);
            app.EMGTab_Feature = uitab(app.TabGroup);
            app.EMGTab_Feature.Title = 'EMG';
            app.btnEMGTop4 = uibutton(app.EMGTab_Feature, 'push');
            app.btnEMGTop4.ButtonPushedFcn = createCallbackFcn(app, @btnEMGTop4ButtonPushed, true);
            app.btnEMGTop4.Text = 'Top 4';
            app.lstEMGFeatures = uilistbox(app.EMGTab_Feature);
            app.lstEMGFeatures.Items = {'tmn', 'tmabs', 'tmd', 'tstd', 'tvar', 'twl', 'trms', 'tzc', 'tpks', 'tmpks', 'tmvel', 'tslpch1', 'tslpch2', 'tpwr', 'tcr', 'tcv', 'tdam    ', 'tmfl', 'tfdh    ', 'tfd', 'tcard', 'tren', 'tareg', 'tmax', 'tmin', 'fwl	', 'fmn	', 'fmd', 'fpmn', 'fpmd	 ', 'fpstd	'};
            app.lstEMGFeatures.Multiselect = 'on';
            app.lstEMGFeatures.Value = {'tmn'};
            app.btnEMGHungPaper = uibutton(app.EMGTab_Feature, 'push');
            app.btnEMGHungPaper.ButtonPushedFcn = createCallbackFcn(app, @btnEMGHungPaperButtonPushed, true);
            app.btnEMGHungPaper.Text = 'Based on Hung Paper';
            app.IMUPSTab_2 = uitab(app.TabGroup);
            app.IMUPSTab_2.Title = 'IMU/PS';
            app.lstIMUFeature = uilistbox(app.IMUPSTab_2);
            app.lstIMUFeature.Items = {'tmn', 'tmabs', 'tmd', 'tstd', 'tvar', 'twl', 'trms', 'tzc', 'tpks', 'tmpks', 'tmvel', 'tslpch1', 'tslpch2', 'tpwr', 'tcr', 'tcv', 'tdam    ', 'tmfl           ', 'tfdh    ', 'tfd               ', 'tcard   ', 'tretareg', 'tmax', 'tmin', 'fwl	', 'fmn	', 'fmd', 'fpmn', 'fpmd	 ', 'fpstd	'};
            app.lstIMUFeature.Multiselect = 'on';
            app.lstIMUFeature.Value = {'tmn'};
            app.btnIMUTop4 = uibutton(app.IMUPSTab_2, 'push');
            app.btnIMUTop4.ButtonPushedFcn = createCallbackFcn(app, @btnIMUTop4ButtonPushed, true);
            app.btnIMUTop4.Text = 'Top 4';
            app.CleanbadroundsCheckBox = uicheckbox(app.OfflineProcessingUIFigure);
            app.CleanbadroundsCheckBox.Tooltip = {'Nullify parts of signal that contain tags of an improper sequence. This greatly eliminates human tagging error.'};
            app.CleanbadroundsCheckBox.Text = 'Clean bad rounds';
            app.CleanbadroundsCheckBox.Value = true;
            app.DataNameLabel = uilabel(app.OfflineProcessingUIFigure);
            app.DataNameLabel.Text = 'DataName';
            % Show the figure after all components are created
            app.OfflineProcessingUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_OfflineProcess

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.OfflineProcessingUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OfflineProcessingUIFigure)
        end
    end
end