classdef ChannelsForDemonstration < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        DoneButton                  matlab.ui.control.Button
        ChoosewhichchanneltodrawMax6Label  matlab.ui.control.Label
        Panel                       matlab.ui.container.Panel
        IMUListBox                  matlab.ui.control.ListBox
        IMUListBoxLabel             matlab.ui.control.Label
        PressureSensorListBox       matlab.ui.control.ListBox
        PressureSensorListBoxLabel  matlab.ui.control.Label
        EMGListBox                  matlab.ui.control.ListBox
        EMGListBoxLabel             matlab.ui.control.Label
    end

    % ---------------------------- Copyright Notice ---------------------------
% This file is part of LocoD © which is open and free software under
% the GNU Lesser General Public License (LGPL). See the file "LICENSE" for
% the full license governing this code and copyrights.
%
% LocoD was initially developed by Bahareh Ahkami at
% Center for Bionics and Pain research and Chalmers University of Technology.
% All authors’ contributions must be kept
% acknowledged below in the section "Updates % Contributors".
%
% Would you like to contribute to science and sum efforts to improve
% amputees’ quality of life? Join this project! or, send your comments to:
% ahkami@chalmers.se.
%
% The entire copyright notice must be kept in this or any source file
% linked to LocoD. This will ensure communication with all authors and
% acknowledge contributions here and in the project web page (optional).

% acknowledge contributions here and in the project web page (optional).
% ------------------- Function Description ------------------
% Choose channles to display GUI
%
% --------------------------Updates--------------------------
% 2022-03-15 / Bahareh Ahkami / Creation
    properties (Access = public)
        MainWindow GUI_LocoD;
        recsess RecordingSession
    end

    properties (Access = private, Dependent)
        recProps
    end
    methods
        function rp = get.recProps(this)
            rp = this.recsess.GetRecProps();
        end
    
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainWindow, recsess)
            app.MainWindow = mainWindow;
            app.recsess=recsess;


            % Population channel tree for Delsys
            listEMG = app.recProps.UnmaskedIdxEMG;
            if app.recProps.UnmaskedHasIMU==1
                listIMU= app.recProps.UnmaskedIdxIMU;
                listIMU=listIMU(1,:);
                strListIMU = strsplit(num2str(listIMU));
                app.IMUListBox.Items=strListIMU;
                
            end
            

            if app.recProps.UnmaskedHasPS==1
                listPS=app.recProps.UnmaskedIdxPS;
                strListPS=strsplit(num2str(listPS));
                app.PressureSensorListBox.Items = strListPS;
            end


            strListEMG=strsplit(num2str(listEMG));
            app.EMGListBox.Items = strListEMG;
            
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
           
            if app.recProps.UnmaskedHasIMU==1 && ~isempty(app.IMUListBox.Value)
%                 k=1;
%                 for i=1:length(app.IMUListBox.Value)  
%                     for j=1:size(app.recProps.IMU_ChannelNames,2)
%                         if app.IMUListBox.Value(i)==app.recProps.IMU_ChannelNames(1,j)  
%                            DIMU(k)= app.recProps.IMU_ChannelNames(2,j);
%                            DrawnIMU(k)=str2double(DIMU(k));
%                            k=k+1;
%                         end
%                     end
%                 end

              DrawnIMU=sort(str2double(app.IMUListBox.Value));
            else
                DrawnIMU = [];
            end

            if app.recProps.UnmaskedHasPS==1 && ~isempty(app.PressureSensorListBox.Value)
                DrawnPS=str2double(sort(app.PressureSensorListBox.Value));
                
            else
                DrawnPS = [];
            end
            if ~isempty(app.EMGListBox.Value)
                DrawnEMG=str2double(sortrows(app.EMGListBox.Value));                
            else
                DrawnEMG = [];
            end

            
            app.recsess.DrawnIdx=[DrawnEMG,DrawnPS,DrawnIMU];
            if length( app.recsess.DrawnIdx)>6
                msgbox("Better to choose only 6 channels")
                %Error and return
            
            end

            %Close this GUI
            closereq();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.EMGListBoxLabel = uilabel(app.Panel);
            app.EMGListBoxLabel.Text = 'EMG';
            app.EMGListBox = uilistbox(app.Panel);
            app.EMGListBox.Items = {};
            app.EMGListBox.Multiselect = 'on';
            app.EMGListBox.Value = {};
            app.PressureSensorListBoxLabel = uilabel(app.Panel);
            app.PressureSensorListBoxLabel.Text = 'Pressure Sensor';
            app.PressureSensorListBox = uilistbox(app.Panel);
            app.PressureSensorListBox.Items = {};
            app.PressureSensorListBox.Multiselect = 'on';
            app.PressureSensorListBox.Value = {};
            app.IMUListBoxLabel = uilabel(app.Panel);
            app.IMUListBoxLabel.Text = 'IMU';
            app.IMUListBox = uilistbox(app.Panel);
            app.IMUListBox.Items = {};
            app.IMUListBox.Multiselect = 'on';
            app.IMUListBox.Value = {};
            app.ChoosewhichchanneltodrawMax6Label = uilabel(app.UIFigure);
            app.ChoosewhichchanneltodrawMax6Label.Text = 'Choose which channel to draw (Max 6)';
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ChannelsForDemonstration(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end