classdef GUI_AFE < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AFEGUIUIFigure                 matlab.ui.Figure
        AddPatientInforamtionButton    matlab.ui.control.Button
        FrequencyDropDown              matlab.ui.control.DropDown
        FrequencyDropDownLabel         matlab.ui.control.Label
        CommunicationDropDown          matlab.ui.control.DropDown
        CommunicationDropDownLabel     matlab.ui.control.Label
        AcquistionDeviceDropDown       matlab.ui.control.DropDown
        AcquistionDeviceDropDownLabel  matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        EMGPSTab                       matlab.ui.container.Tab
        PSDropDown                     matlab.ui.control.DropDown
        SensorDropDownLabel            matlab.ui.control.Label
        PressureThresholdEditField     matlab.ui.control.EditField
        PressureThresholdEditFieldLabel  matlab.ui.control.Label
        PressureSensorCheckBox         matlab.ui.control.CheckBox
        UseshiftclicktoselectmorethanonesensorLabel  matlab.ui.control.Label
        EMGSensorsListBox              matlab.ui.control.ListBox
        EMGSensorsListBoxLabel         matlab.ui.control.Label
        IMUTab                         matlab.ui.container.Tab
        IMUCheckBox                    matlab.ui.control.CheckBox
        SensorsLabel                   matlab.ui.control.Label
        ImuSnsrTree                    matlab.ui.container.CheckBoxTree
        CommunicationDropDown_2        matlab.ui.control.DropDown
        CommunicationDropDown_2Label   matlab.ui.control.Label
        DeviceDropDown_3               matlab.ui.control.DropDown
        DeviceDropDown_3Label          matlab.ui.control.Label
        FrequencyDropDown_2            matlab.ui.control.DropDown
        FrequencyDropDown_2Label       matlab.ui.control.Label
        CheckpressurethresholdButton   matlab.ui.control.Button
        PrintRecordingPropsButton      matlab.ui.control.Button
        StartRecordingButton           matlab.ui.control.Button
    end


    properties (Access = public)
        recProps RecordingProperties;
        recSess  RecordingFunctions;
    end

    properties (Access = private)
        MainWindow GUI_LocoD; % The window (app) who opened me
        RecordingApp GUI_Recording; % Reference to the spawned recording app
        channelselection ChannelsForDemonstration 
    end
    
    methods (Access = private)
        
        function okay = CalculateRecProps(app)
            app.recProps.Device= app.AcquistionDeviceDropDown.Value;
            app.recProps.SamplingFreq=str2double(app.FrequencyDropDown.Value);
            app.recProps.ComType=app.CommunicationDropDown.Value;
            app.recProps.IMU_Device=app.DeviceDropDown_3.Value;
            app.recProps.IMU_SamplingFreq=str2double(app.FrequencyDropDown_2.Value);
            app.recProps.IMU_ComType=app.CommunicationDropDown_2.Value;
            okay = 1;

            app.recProps.PressureThreshold = (str2double(app.PressureThresholdEditField.Value));
            
            enableIMU = app.IMUCheckBox.Value==1;
            enablePS = app.PressureSensorCheckBox.Value==1;

            if enableIMU
                checkedNodes = app.ImuSnsrTree.CheckedNodes;
                %Save Checked Nodes
                k=1;
                SavedNode=string([]);
                for j=1:length(checkedNodes)
                    if contains(checkedNodes(j).Text,"Sensor")==0
                        SavedNode(k)=checkedNodes(j).Text;
                        k=k+1;
                    end
                end
                app.recProps.IMU_ChannelNames=SavedNode;
                imuChs = [];

                for i=1:length(checkedNodes)          
                    if ~isempty(checkedNodes(i).NodeData)
                        imuChs(end + 1) = checkedNodes(i).NodeData;
                    end
                end
               
                app.recProps.ChannelSelectionIMU = sort(imuChs);
                app.recProps.IMU_ChannelNames=[SavedNode;app.recProps.ChannelSelectionIMU];
                %app.recProps.IMU_ChannelNames=[SavedNode;app.recProps.IdxIMU];
            else
                app.recProps.ChannelSelectionIMU = [];
            end

            if enablePS
                app.recProps.ChannelSelectionPS = str2double(app.PSDropDown.Value);
            else
                app.recProps.ChannelSelectionPS = [];
            end

            app.recProps.ChannelSelectionEMG=str2double(sortrows(app.EMGSensorsListBox.Value));


            % Ensure everything makes sense!
            try
                app.recProps.Verify()
            catch Ex
                okay = 0;
                msgbox(Ex.message, 'Error', 'error')
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainWindow)
            % Store handle to main window. Could be useful.
            app.MainWindow = mainWindow;

            % Create a brand new recordingProperties object.
            app.recProps = RecordingProperties();

            % Population channel tree for Delsys
            listSnsr = 1:16;
            strListSnsr = strsplit(num2str(listSnsr));
            app.PSDropDown.Items = strListSnsr;
            app.EMGSensorsListBox.Items = strListSnsr;

            for snsr = listSnsr
                snsrNode = uitreenode(app.ImuSnsrTree);
                snsrNode.Text = sprintf("Sensor %d", snsr);

                delsysChannelOffset = (snsr - 1) * 9 + 1;
                snsrName = sprintf("S%d", snsr); 

                for channel = {'_ACCX', '_ACCY', '_ACCZ', '_GYRX', '_GYRY', '_GYRZ'}
                    chNode = uitreenode(snsrNode);
                    chNode.Text = strcat(snsrName, channel{1});
                    chNode.NodeData = delsysChannelOffset;
                    delsysChannelOffset = delsysChannelOffset + 1;
                end
            end
        end

        % Button pushed function: PrintRecordingPropsButton
        function PrintRecordingPropsButtonPushed(app, event)
            app.CalculateRecProps();
            app.recProps.Who();
        end

        % Value changed function: IMUCheckBox
        function IMUCheckBox_2ValueChanged(app, event)
            if app.IMUCheckBox.Value==1
                app.ImuSnsrTree.Visible=1;
            end
        end

        % Button pushed function: StartRecordingButton
        function StartRecordingButtonPushed(app, event)

            if ~app.CalculateRecProps()
                return
            end

            % Create a recording session and start it
            app.recSess = RecordingFunctions(app.recProps);
            app.recSess.ConnectToDevice();
            app.RecordingApp = GUI_Recording(app.MainWindow, app.recSess);
            app.channelselection=ChannelsForDemonstration(app.MainWindow,app.recSess);

            %Choose channles to show
            %Which channels to show

        end

        % Button pushed function: CheckpressurethresholdButton
        function CheckpressurethresholdButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AFEGUIUIFigure = uifigure('Visible', 'off');
            app.AFEGUIUIFigure.Name = 'AFE GUI';
            app.StartRecordingButton = uibutton(app.AFEGUIUIFigure, 'push');
            app.StartRecordingButton.ButtonPushedFcn = createCallbackFcn(app, @StartRecordingButtonPushed, true);
            app.StartRecordingButton.Text = 'Start Recording';
            app.PrintRecordingPropsButton = uibutton(app.AFEGUIUIFigure, 'push');
            app.PrintRecordingPropsButton.ButtonPushedFcn = createCallbackFcn(app, @PrintRecordingPropsButtonPushed, true);
            app.PrintRecordingPropsButton.Text = 'Print Recording Props';
            app.CheckpressurethresholdButton = uibutton(app.AFEGUIUIFigure, 'push');
            app.CheckpressurethresholdButton.ButtonPushedFcn = createCallbackFcn(app, @CheckpressurethresholdButtonPushed, true);
            app.CheckpressurethresholdButton.Text = 'Check pressure threshold';
            app.TabGroup = uitabgroup(app.AFEGUIUIFigure);
            app.EMGPSTab = uitab(app.TabGroup);
            app.EMGPSTab.Title = 'EMG / PS';
            app.EMGSensorsListBoxLabel = uilabel(app.EMGPSTab);
            app.EMGSensorsListBoxLabel.Text = 'EMG Sensors';
            app.EMGSensorsListBox = uilistbox(app.EMGPSTab);
            app.EMGSensorsListBox.Items = {};
            app.EMGSensorsListBox.Multiselect = 'on';
            app.EMGSensorsListBox.Value = {};
            app.UseshiftclicktoselectmorethanonesensorLabel = uilabel(app.EMGPSTab);
            app.UseshiftclicktoselectmorethanonesensorLabel.Text = 'Use shift + click to select more than one sensor.';
            app.PressureSensorCheckBox = uicheckbox(app.EMGPSTab);
            app.PressureSensorCheckBox.Text = 'Pressure Sensor';
            app.PressureThresholdEditFieldLabel = uilabel(app.EMGPSTab);
            app.PressureThresholdEditFieldLabel.Text = 'Pressure Threshold';
            app.PressureThresholdEditField = uieditfield(app.EMGPSTab, 'text');
            app.PressureThresholdEditField.Value = '7';
            app.SensorDropDownLabel = uilabel(app.EMGPSTab);
            app.SensorDropDownLabel.Text = 'Sensor';
            app.PSDropDown = uidropdown(app.EMGPSTab);
            app.PSDropDown.Items = {};
            app.PSDropDown.Value = {};
            app.IMUTab = uitab(app.TabGroup);
            app.IMUTab.Title = 'IMU';
            app.FrequencyDropDown_2Label = uilabel(app.IMUTab);
            app.FrequencyDropDown_2Label.Visible = 'off';
            app.FrequencyDropDown_2Label.Text = 'Frequency';
            app.FrequencyDropDown_2 = uidropdown(app.IMUTab);
            app.FrequencyDropDown_2.Items = {'500', '1000', '2000', '4000', ''};
            app.FrequencyDropDown_2.Visible = 'off';
            app.FrequencyDropDown_2.Value = '2000';
            app.DeviceDropDown_3Label = uilabel(app.IMUTab);
            app.DeviceDropDown_3Label.Visible = 'off';
            app.DeviceDropDown_3Label.Text = 'Device';
            app.DeviceDropDown_3 = uidropdown(app.IMUTab);
            app.DeviceDropDown_3.Items = {'DELSYS_Trigno_IMU', 'dummy'};
            app.DeviceDropDown_3.Visible = 'off';
            app.DeviceDropDown_3.Value = 'DELSYS_Trigno_IMU';
            app.CommunicationDropDown_2Label = uilabel(app.IMUTab);
            app.CommunicationDropDown_2Label.Visible = 'off';
            app.CommunicationDropDown_2Label.Text = 'Communication';
            app.CommunicationDropDown_2 = uidropdown(app.IMUTab);
            app.CommunicationDropDown_2.Items = {'WiFi', 'Com', 'NI'};
            app.CommunicationDropDown_2.Visible = 'off';
            app.CommunicationDropDown_2.Value = 'WiFi';
            app.ImuSnsrTree = uitree(app.IMUTab, 'checkbox');
            app.ImuSnsrTree.Visible = 'off';
            app.SensorsLabel = uilabel(app.IMUTab);
            app.SensorsLabel.Text = 'Sensors';
            app.IMUCheckBox = uicheckbox(app.IMUTab);
            app.IMUCheckBox.ValueChangedFcn = createCallbackFcn(app, @IMUCheckBox_2ValueChanged, true);
            app.IMUCheckBox.Text = 'IMU';
            app.Label = uilabel(app.AFEGUIUIFigure);
            app.Label.Visible = 'off';
            app.Label.Text = '⬇';
            app.AcquistionDeviceDropDownLabel = uilabel(app.AFEGUIUIFigure);
            app.AcquistionDeviceDropDownLabel.Text = 'Acquistion Device';
            app.AcquistionDeviceDropDown = uidropdown(app.AFEGUIUIFigure);
            app.AcquistionDeviceDropDown.Items = {'DELSYS_Trigno', 'dummy'};
            app.AcquistionDeviceDropDown.Value = 'DELSYS_Trigno';
            app.CommunicationDropDownLabel = uilabel(app.AFEGUIUIFigure);
            app.CommunicationDropDownLabel.Text = 'Communication';
            app.CommunicationDropDown = uidropdown(app.AFEGUIUIFigure);
            app.CommunicationDropDown.Items = {'WiFi', 'Com', 'NI'};
            app.CommunicationDropDown.Value = 'WiFi';
            app.FrequencyDropDownLabel = uilabel(app.AFEGUIUIFigure);
            app.FrequencyDropDownLabel.Text = 'Frequency';
            app.FrequencyDropDown = uidropdown(app.AFEGUIUIFigure);
            app.FrequencyDropDown.Items = {'500', '1000', '2000', '4000', ''};
            app.FrequencyDropDown.Value = '2000';
            app.AddPatientInforamtionButton = uibutton(app.AFEGUIUIFigure, 'push');
            app.AddPatientInforamtionButton.Text = 'Add Patient Inforamtion';
            % Show the figure after all components are created
            app.AFEGUIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_AFE(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AFEGUIUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AFEGUIUIFigure)
        end
    end
end