classdef GUI_ChannelMask < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ChannelMaskUIFigure  matlab.ui.Figure
        btnCancel            matlab.ui.control.Button
        btnApply             matlab.ui.control.Button
        trChannels           matlab.ui.container.CheckBoxTree
        lblSignal            matlab.ui.control.Label
        lblExplain           matlab.ui.control.Label
    end

    % ---------------------------- Copyright Notice ---------------------------
% This file is part of LocoD © which is open and free software under
% the GNU Lesser General Public License (LGPL). See the file "LICENSE" for
% the full license governing this code and copyrights.
%
% LocoD was initially developed by Bahareh Ahkami at
% Center for Bionics and Pain research and Chalmers University of Technology.
% All authors’ contributions must be kept
% acknowledged below in the section "Updates % Contributors".
%
% Would you like to contribute to science and sum efforts to improve
% amputees’ quality of life? Join this project! or, send your comments to:
% ahkami@chalmers.se.
%
% The entire copyright notice must be kept in this or any source file
% linked to LocoD. This will ensure communication with all authors and
% acknowledge contributions here and in the project web page (optional).

% acknowledge contributions here and in the project web page (optional).
% ------------------- Function Description ------------------
% Masking channels GUI
%
% --------------------------Updates--------------------------
% 2022-03-15 / Bahareh Ahkami / Creation
    properties (Access = private)
        recProps RecordingProperties;
    end
    
    methods (Access = private)
        function AddNodesForGroup(app, idxGroup, groupName)
            N = uitreenode(app.trChannels);
            N.NodeData = {"", 0};
            N.Text = groupName;

            channelMaskCurrent = app.recProps.ChannelMaskGet();
            checkedArray = [];
            
            cnt = 1;
            for ch = idxGroup
                ChN = uitreenode(N);
                ChN.NodeData = 0;
                ChN.Text = sprintf("Ch #%d, %s %d", ch, groupName, cnt);
                ChN.NodeData = {groupName, ch};
                if channelMaskCurrent(ch) > 0
                    % TODO: Preallocate checkedArray
                    checkedArray = [checkedArray; ChN];
                end
                cnt = cnt + 1;
            end

            % Check some boxes
            app.trChannels.CheckedNodes = [app.trChannels.CheckedNodes; checkedArray];
        end

        function UpdateTexts(app)
            % Here, note that the changes in the listbox are not yet
            % applied to the recProps (they are applied in Apply button).
            % Therefore, the statistics for unmasked channels cannot be
            % read from recProps (yet).
            checkedNodes = app.trChannels.CheckedNodes;
            nEMG = 0;
            nIMU = 0;
            nPS = 0;
            for iN = 1:length(checkedNodes)
                N = checkedNodes(iN);
                
                % Type of the channel is embedded in node data of the node.
                chType = N.NodeData{1};
                if strcmp(chType, "EMG")
                    nEMG = nEMG + 1;
                elseif strcmp(chType, "IMU")
                    nIMU = nIMU + 1;
                elseif strcmp(chType, "PS")
                    nPS = nPS + 1; 
                end
            end

            txt = sprintf("Select a subset of channels to be used in the " + ...
                "signal processing. Other channels will be masked away.\n\n" + ...
                "Total available channels:\n" + ...
                "%d EMG, %d PS, %d IMU\n\nUnmasked channels:\n" + ...
                "%d EMG, %d PS, %d IMU", ...
                app.recProps.UnmaskedNumEMGCh, app.recProps.UnmaskedNumPSCh, app.recProps.UnmaskedNumIMUCh, ...
                nEMG, nPS, nIMU);

            app.lblSignal.Text = txt;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, recProps)
            app.recProps = recProps;
            
            % Populate Node Tree
            app.trChannels.CheckedNodes = [];
            app.AddNodesForGroup(app.recProps.UnmaskedIdxEMG, "EMG");
            app.AddNodesForGroup(app.recProps.UnmaskedIdxPS, "PS");
            app.AddNodesForGroup(app.recProps.UnmaskedIdxIMU, "IMU");

            app.UpdateTexts();
        end

        % Button pushed function: btnCancel
        function btnCancelButtonPushed(app, event)
            delete(app)
        end

        % Callback function: trChannels
        function trChannelsCheckedNodesChanged(app, event)
            app.UpdateTexts()
        end

        % Button pushed function: btnApply
        function btnApplyButtonPushed(app, event)
            % Change channel mask to include only the checked channels
            % First start with an all-excluded list.
            app.recProps.ChannelMaskUnset();
            mask = zeros(1, app.recProps.UnmaskedNumAllCh);
            checkedNodes = app.trChannels.CheckedNodes;
            for iN = 1:length(checkedNodes)
                N = checkedNodes(iN);
                
                % Index of the channel is embedded in node data of the node.
                chIdx = N.NodeData{2};
                if chIdx < 1
                    % Ignore this node (One of the top level nodes in the
                    % tree)
                    continue
                end

                if chIdx >= 1
                    % Include this channel by setting mask to 1.
                    mask(chIdx) = 1;
                end
            end

            % Apply and exit
            % Also we could use app.recProps.ChannelMaskInclude()
            app.recProps.ChannelMaskSet(mask);
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ChannelMaskUIFigure = uifigure('Visible', 'off');
            app.ChannelMaskUIFigure.Name = 'Channel Mask';
            app.lblExplain = uilabel(app.ChannelMaskUIFigure);
            app.lblExplain.Interpreter = 'html';
            app.lblExplain.Text = {'<b>Explation: </b>The channel mask will be defined as a logical vector (e.g. [1 1 0 0 1 1 ...]). The memory and storage form of the signal will be unaffected, but when checking the signal''s specs through the "Recording Properties - RecProps" (including number of channels, indices of the channels, etc., such as recProps.IdxEMG) it will appear as if the original signal lacks the masked channels. Therefore, some rows in the signal matrix will be left unused.'; 'This affects all processing of the signal, as well as the replay feature.'};
            app.lblSignal = uilabel(app.ChannelMaskUIFigure);
            app.lblSignal.Text = 'Unmasked Signal:';
            app.trChannels = uitree(app.ChannelMaskUIFigure, 'checkbox');
            % Assign Checked Nodes
            app.trChannels.CheckedNodesChangedFcn = createCallbackFcn(app, @trChannelsCheckedNodesChanged, true);
            app.btnApply = uibutton(app.ChannelMaskUIFigure, 'push');
            app.btnApply.ButtonPushedFcn = createCallbackFcn(app, @btnApplyButtonPushed, true);
            app.btnApply.Text = 'Apply';
            app.btnCancel = uibutton(app.ChannelMaskUIFigure, 'push');
            app.btnCancel.ButtonPushedFcn = createCallbackFcn(app, @btnCancelButtonPushed, true);
            app.btnCancel.Text = 'Cancel';
            % Show the figure after all components are created
            app.ChannelMaskUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_ChannelMask(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ChannelMaskUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ChannelMaskUIFigure)
        end
    end
end