classdef GUI_Recording < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RecordingSessionUIFigure  matlab.ui.Figure
        StreamButton              matlab.ui.control.Button
        StatsGUICheckBox          matlab.ui.control.CheckBox
        StandStiffButton          matlab.ui.control.Button
        PauseButton               matlab.ui.control.Button
        PrintrecpropsButton       matlab.ui.control.Button
        RecordButton              matlab.ui.control.Button
        RampDescentButton         matlab.ui.control.Button
        RampAscentButton          matlab.ui.control.Button
        StairDescentButton        matlab.ui.control.Button
        StairAscentButton         matlab.ui.control.Button
        WalkingButton             matlab.ui.control.Button
        StandupButton             matlab.ui.control.Button
        SitdownButton             matlab.ui.control.Button
        ProcessButton             matlab.ui.control.Button
        StopandSaveButton         matlab.ui.control.Button
        UIAxes                    matlab.ui.control.UIAxes
    end

% ---------------------------- Copyright Notice ---------------------------
% This file is part of LocoD © which is open and free software under
% the GNU Lesser General Public License (LGPL). See the file "LICENSE" for
% the full license governing this code and copyrights.
%
% LocoD was initially developed by Bahareh Ahkami at
% Center for Bionics and Pain research and Chalmers University of Technology.
% All authors’ contributions must be kept
% acknowledged below in the section "Updates % Contributors".
%
% Would you like to contribute to science and sum efforts to improve
% amputees’ quality of life? Join this project! or, send your comments to:
% ahkami@chalmers.se.
%
% The entire copyright notice must be kept in this or any source file
% linked to LocoD. This will ensure communication with all authors and
% acknowledge contributions here and in the project web page (optional).

% acknowledge contributions here and in the project web page (optional).
% ------------------- Function Description ------------------
% Recording GUI
%
% --------------------------Updates--------------------------
% 2022-03-15 / Bahareh Ahkami / Creation
    properties (Access = public)
        MainWindow GUI_LocoD;
        recSess  RecordingSession;
        OffProcess OfflineProcess;
        Stats   GUI_Stats;
    end

    properties (Access = private)
        reentrantRedrawSentinel = 0;
        lastFrameTic = 0;
    end

    properties (Constant)
        signalRenderDownsample = 1;
        minFrameTime = 0.2;
    end

    properties (Access = private, Dependent)
        recProps
    end

    methods
        function rp = get.recProps(this)
            rp = this.recSess.GetRecProps();
        end
    end

    methods (Access = private)
        function performRedraw(app, rs)
            % Params
            yHeightCh = 5; % units
            DisplaySignalLen = 15.0; % seconds
            NormalizeScanLen = 10.0; % seconds
            signals = rs;
            %[nCh, nSmp] = size(rs.signal);
            [~, nSmp] = size(rs.signal);

            %Rethink this part(One note)
            nCh=length(app.recSess.DrawnIdx);
            %             %NewIdxEMG=1:length(app.recSess.DrawnEMG);
            %             if app.recProps.HasPS==1  && ~isempty(app.recSess.DrawnPS)
            %                 NewIdxPS=length(app.recSess.DrawnEMG)+length(app.recSess.DrawnPS);
            %             else
            %                 NewIdxPS=[];
            %             end
            %             if app.recProps.HasIMU==1 && app.recProps.HasPS==1 &&  ~isempty(app.recSess.DrawnIMU)
            %                 NewIdxIMU=length(app.recSess.DrawnPS)+length(app.recSess.DrawnEMG)+1:length(app.recSess.DrawnIMU)+length(app.recSess.DrawnPS)+length(app.recSess.DrawnEMG);
            %             elseif app.recProps.HasIMU==1 && app.recProps.HasPS==0 &&  ~isempty(app.recSess.DrawnIMU)
            %                 NewIdxIMU=length(app.recSess.DrawnEMG)+1:length(app.recSess.DrawnIMU)+length(app.recSess.DrawnEMG);
            %             else
            %                 NewIdxIMU=[];
            %             end
            FS = app.recProps.SamplingFreq;

            if nSmp == 0 || nCh == 0
                % What the?
                return
            end

            % Decide on signal time range to display
            t1 = nSmp;
            t0 = max(floor(t1 - DisplaySignalLen * FS + 1), 1);
            t = (t0:t1);
            tReal = t / FS;
            tReal1 = tReal(end);
            %tReal0 = tReal(1);
            tReal0 = tReal1 - DisplaySignalLen;

            % We assume yHeightCh vertical units size for every row (channel),
            %   however we give more swing range for each channel.
            % Rational is that channels will touch the extremes less
            %   often, so let's use that space.
            yExtraSwingCh = 0.2 * yHeightCh;
            yTotalSwingCh = yExtraSwingCh + yHeightCh;
            yHeight = yHeightCh * nCh;

            % Find swing center point for each channel
            yOffset = yHeightCh * ((1 : nCh) - 0.5);

            % Decide on time range scan to find signal value range
            %   This is used for normalization.
            t1Norm = nSmp;
            t0Norm = max(floor(t1Norm - NormalizeScanLen * FS + 1), 1);
            tNorm = t0Norm:t1Norm;

            % Find normalization factor for various signal subgroups.
            epsilon = 1e-8;
            sigRangeEMG = 0.5 * yTotalSwingCh / max(epsilon, max(abs(rs.signal(app.recProps.IdxEMG, tNorm)), [], 'all'));
            if app.recProps.HasIMU
                sigRangeIMU = 0.5 * yTotalSwingCh / max(epsilon, max(abs(rs.signal(app.recProps.IdxIMU, tNorm)), [], 'all'));
            end
            if app.recProps.HasPS
                % PS is looks like a square wave. Aesthetically better
                % not to use the extra swing range.
                sigRangePS =  0.5 * yTotalSwingCh;% /20; %max(epsilon, max(abs(rs.signal(app.recProps.IdxPS, tNorm)), [], 'all'));
            end

            % Get overall list of channels to draw
            % drawnIdx = [app.recSess.DrawnEMG; app.recSess.DrawnPS, app.recSess.DrawnIMU];
            drawnIdx=app.recSess.DrawnIdx;


            nChDrawn = length(drawnIdx);

            % Get type of all channels
            typeOfCh = app.recProps.GetChannelTypes();

            % Draw
            if length(app.UIAxes.Children) ~= nChDrawn + 1 % plus 1 for tag lines
                % Probably this is the first iteration of running the
                % graph.
                setupAxes = 1;
            else
                % Graph is already set. Only change data.
                setupAxes = 0;
            end

            if setupAxes
                % Setup the graph
                hold(app.UIAxes, 'off');
                set(app.UIAxes,'YTick', yOffset);
                set(app.UIAxes,'YTickLabel', 0:nCh-1);
            end

            % Draw tag lines
            %  For each tag, put three points.
            % Can you find out the magic here?
            tagLinesX = [];
            tagLinesY = [];
            if ~isempty(rs.tags)  % ??! if rs.tagcounter > 0

                tagTimes = rs.tags(2, :);
            else
                tagTimes = [0];
            end
            tagLinesX = repelem(tagTimes, 3); % x3
            tagLinesY = repmat([0 - yExtraSwingCh, yHeight + yExtraSwingCh, 0 - yExtraSwingCh], [1 length(tagTimes)]);
            if setupAxes
                plot(app.UIAxes, tagLinesX, tagLinesY, 'k');
                hold(app.UIAxes, 'on');
            else
                app.UIAxes.Children(nChDrawn+1).XData = tagLinesX;
                app.UIAxes.Children(nChDrawn+1).YData = tagLinesY;
            end

            % Draw all channels
            for c = 1 : nChDrawn
                actualChannelIndex = drawnIdx(c);

                if typeOfCh(actualChannelIndex) == 1
                    yRange = sigRangePS;
                    y = -(rs.signal(actualChannelIndex, t)-8.5) * yRange + yOffset(c);
                elseif typeOfCh(actualChannelIndex) == 2
                    yRange = sigRangeIMU;
                    y = rs.signal(actualChannelIndex, t) * yRange + yOffset(c);
                else
                    yRange = sigRangeEMG;
                    y = rs.signal(actualChannelIndex, t) * yRange + yOffset(c);
                end
                

                if setupAxes
                    % First run
                    plot(app.UIAxes, downsample(tReal, app.signalRenderDownsample), downsample(y, app.signalRenderDownsample));
                    hold(app.UIAxes, 'on');
                else
                    % Objects already created. Only change data
                    app.UIAxes.Children(c).XData = tReal;
                    app.UIAxes.Children(c).YData = y;

                end
            end

            % Set graph limits
            app.UIAxes.YLim = [0 - yExtraSwingCh / 2, yHeight + yExtraSwingCh];
            app.UIAxes.XLim = [tReal0, tReal1];

            % Disable interactions with the axes -- Otherwise the speed
            % is bu*****p.
            ax = app.UIAxes;
            ax.Interactions = [];
            ax.Toolbar.Visible = 'off';
            disableDefaultInteractivity(ax)

            % Call redraw
            %drawnow

            % Done with drawing
        end

        function onDataInCallback(app, rs, ~)
            if app.reentrantRedrawSentinel == 0
                app.reentrantRedrawSentinel = 1;
                try
                    now = tic;
                    deltaTime = double(now - app.lastFrameTic) / 1.0e7; % 1.0e7 is resolution of tic()
                    if deltaTime > app.minFrameTime
                        app.lastFrameTic = now;
                        app.performRedraw(rs);
                    else
                        % Not enought time passed
                    end

                    if deltaTime < 0
                        % Usually should not get here.
                        app.lastFrameTic = now;
                    end

                    app.reentrantRedrawSentinel = 0;
                catch Ex
                    app.reentrantRedrawSentinel = 0;
                    gprlog('* Exception in Redraw of Graph: %s', getReport(Ex));
                    rethrow(Ex);
                end
            end
        end


        function TagsPlotter(app,rs,tagvalue)
            t_moment=rs.signal.GetTime();
            rs.signal.AddGroundTruthTags(tagvalue,t_moment);
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainWindow, initRecSess)
            app.MainWindow = mainWindow;
            app.recSess=initRecSess;

        end

        % Button pushed function: RecordButton
        function RecordButtonPushed(app, event)
            if app.StatsGUICheckBox.Value==1
                app.Stats=GUI_Stats();
            end
            app.recSess.signal.onSignalCallback = @app.onDataInCallback;
            app.recSess.StartRecording();
            app.reentrantRedrawSentinel = 0;

        end

        % Button pushed function: StreamButton
        function StreamButtonPushed(app, event)
           app.recSess.RecordingStat=0;
            app.recSess.signal.onSignalCallback = @app.onDataInCallback;
            app.recSess.StartRecording();
            app.reentrantRedrawSentinel = 0;
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            if app.recSess.RecordingStat==1
                app.PauseButton.Text="Resume";
                app.RecordButton.Enable=0;
                app.recSess.RecordingStat=0;
            elseif app.recSess.RecordingStat==0
                app.PauseButton.Text="Pause";
                app.recSess.RecordingStat=1;
                app.RecordButton.Enable=1;
            end

            app.reentrantRedrawSentinel = 0; % clear for safety
        end

        % Button pushed function: StopandSaveButton
        function StopandSaveButtonPushed(app, event)
            app.recSess.StopRecording();
            app.recSess.signal.SaveData();

            %uisave(app.recSess.signal)
        end

        % Button pushed function: PrintrecpropsButton
        function PrintrecpropsButtonPushed(app, event)
            app.recProps.Who();
        end

        % Button pushed function: SitdownButton
        function SitdownButtonPushed(app, event)
            %app.recSess.tagcounter=app.recSess.tagcounter+1;
            TagsPlotter(app,app.recSess,1)
        end

        % Button pushed function: StandupButton
        function StandupButtonPushed(app, event)
            %app.recSess.tagcounter=app.recSess.tagcounter+1;
            TagsPlotter(app,app.recSess,2)
        end

        % Button pushed function: StandStiffButton
        function StandStiffButtonPushed(app, event)
            TagsPlotter(app,app.recSess,8)
        end

        % Button pushed function: WalkingButton
        function WalkingButtonPushed(app, event)
            %app.signal.tag=app.signal.tag();
            TagsPlotter(app,app.recSess,3)
        end

        % Button pushed function: RampAscentButton
        function RampAscentButtonPushed(app, event)
            TagsPlotter(app,app.recSess,4)
        end

        % Button pushed function: RampDescentButton
        function RampDescentButtonPushed(app, event)
            TagsPlotter(app,app.recSess,5)
        end

        % Button pushed function: StairAscentButton
        function StairAscentButtonPushed(app, event)
            TagsPlotter(app,app.recSess,6)


        end

        % Button pushed function: StairDescentButton
        function StairDescentButtonPushed(app, event)
            TagsPlotter(app,app.recSess,7)
        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
            app.OffProcess=OfflineProcess();

        end

        % Key press function: RecordingSessionUIFigure
        function RecordingSessionUIFigureKeyPress(app, event)
            key = event.Key;
            switch key


                case 'numpad1'

                    TagsPlotter(app,app.recSess,1);
                case 'numpad2'

                    TagsPlotter(app,app.recSess,2);
                case 'numpad3'

                    TagsPlotter(app,app.recSess,3);
                case 'numpad4'

                    TagsPlotter(app,app.recSess,4);
                    if app.StatsGUICheckBox.Value==1
                        app.Stats.Label.Text=num2str(str2double(app.Stats.Label.Text)+1);
                        app.recProps.RoundNum=app.recProps.RoundNum+1;
                    end
                case 'numpad5'

                    TagsPlotter(app,app.recSess,5);
                case 'numpad6'

                    TagsPlotter(app,app.recSess,6);
                case 'numpad7'

                    TagsPlotter(app,app.recSess,7);
                case 'numpad8'

                    TagsPlotter(app,app.recSess,8);
                case '0'
                    %app.Stats.UpdateRounds();

                    if app.StatsGUICheckBox.Value==1
                        app.Stats.Label.Text=num2str(str2double(app.Stats.Label.Text)+1);
                        app.recProps.RoundNum=app.recProps.RoundNum+1;
                    end
                case 'numpad0'
                    %app.Stats.UpdateRounds();
                    if app.StatsGUICheckBox.Value==1
                        app.Stats.Label.Text=num2str(str2double(app.Stats.Label.Text)+1);
                        app.recProps.RoundNum=app.recProps.RoundNum+1;
                    end
                case'return' %Start
                    app.recSess.signal.onSignalCallback = @app.onDataInCallback;
                    app.recSess.StartRecording();


                case 'add'  %Pause
                    if app.recSess.RecordingStat==1
                        app.PauseButton.Text="Resume";
                        app.recSess.RecordingStat=0;
                    elseif app.recSess.RecordingStat==0
                        app.PauseButton.Text="Pause";
                        app.recSess.RecordingStat=1;
                    end

                case 'subtract' % Stop and save
                    app.recSess.StopRecording();
                    app.recSess.signal.SaveData();

            end

        end

        % Close request function: RecordingSessionUIFigure
        function RecordingSessionUIFigureCloseRequest(app, event)
            % Ensure that recSess is stopping
            % Otherwise we get a dangling recSess + data poll timer
            if ~isempty(app.recSess)
                app.recSess.StopRecording();
                app.recSess.Disconnect();
            end
            
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RecordingSessionUIFigure = uifigure('Visible', 'off');
            app.RecordingSessionUIFigure.Name = 'Recording Session';
            app.RecordingSessionUIFigure.CloseRequestFcn = createCallbackFcn(app, @RecordingSessionUIFigureCloseRequest, true);
            app.RecordingSessionUIFigure.KeyPressFcn = createCallbackFcn(app, @RecordingSessionUIFigureKeyPress, true);
            app.UIAxes = uiaxes(app.RecordingSessionUIFigure);
            title(app.UIAxes, 'Real Time Data')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.StopandSaveButton = uibutton(app.RecordingSessionUIFigure, 'push');
            app.StopandSaveButton.ButtonPushedFcn = createCallbackFcn(app, @StopandSaveButtonPushed, true);
            app.StopandSaveButton.Text = 'Stop and Save';
            app.ProcessButton = uibutton(app.RecordingSessionUIFigure, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Text = 'Process';
            app.SitdownButton = uibutton(app.RecordingSessionUIFigure, 'push');
            app.SitdownButton.ButtonPushedFcn = createCallbackFcn(app, @SitdownButtonPushed, true);
            app.SitdownButton.Text = 'Sit down';
            app.StandupButton = uibutton(app.RecordingSessionUIFigure, 'push');
            app.StandupButton.ButtonPushedFcn = createCallbackFcn(app, @StandupButtonPushed, true);
            app.StandupButton.Text = 'Stand up';
            app.WalkingButton = uibutton(app.RecordingSessionUIFigure, 'push');
            app.WalkingButton.ButtonPushedFcn = createCallbackFcn(app, @WalkingButtonPushed, true);
            app.WalkingButton.Text = 'Walking';
            app.StairAscentButton = uibutton(app.RecordingSessionUIFigure, 'push');
            app.StairAscentButton.ButtonPushedFcn = createCallbackFcn(app, @StairAscentButtonPushed, true);
            app.StairAscentButton.Text = 'Stair Ascent';
            app.StairDescentButton = uibutton(app.RecordingSessionUIFigure, 'push');
            app.StairDescentButton.ButtonPushedFcn = createCallbackFcn(app, @StairDescentButtonPushed, true);
            app.StairDescentButton.Text = 'Stair Descent';
            app.RampAscentButton = uibutton(app.RecordingSessionUIFigure, 'push');
            app.RampAscentButton.ButtonPushedFcn = createCallbackFcn(app, @RampAscentButtonPushed, true);
            app.RampAscentButton.Text = 'Ramp Ascent';
            app.RampDescentButton = uibutton(app.RecordingSessionUIFigure, 'push');
            app.RampDescentButton.ButtonPushedFcn = createCallbackFcn(app, @RampDescentButtonPushed, true);
            app.RampDescentButton.Text = 'Ramp Descent';
            app.RecordButton = uibutton(app.RecordingSessionUIFigure, 'push');
            app.RecordButton.ButtonPushedFcn = createCallbackFcn(app, @RecordButtonPushed, true);
            app.RecordButton.Text = 'Record';
            app.PrintrecpropsButton = uibutton(app.RecordingSessionUIFigure, 'push');
            app.PrintrecpropsButton.ButtonPushedFcn = createCallbackFcn(app, @PrintrecpropsButtonPushed, true);
            app.PrintrecpropsButton.Text = 'Print recprops';
            app.PauseButton = uibutton(app.RecordingSessionUIFigure, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = 'Pause';
            app.StandStiffButton = uibutton(app.RecordingSessionUIFigure, 'push');
            app.StandStiffButton.ButtonPushedFcn = createCallbackFcn(app, @StandStiffButtonPushed, true);
            app.StandStiffButton.Text = 'Stand/Stiff';
            app.StatsGUICheckBox = uicheckbox(app.RecordingSessionUIFigure);
            app.StatsGUICheckBox.Text = 'Stats GUI';
            app.StreamButton = uibutton(app.RecordingSessionUIFigure, 'push');
            app.StreamButton.ButtonPushedFcn = createCallbackFcn(app, @StreamButtonPushed, true);
            app.StreamButton.Text = 'Stream';
            % Show the figure after all components are created
            app.RecordingSessionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_Recording(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RecordingSessionUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RecordingSessionUIFigure)
        end
    end
end