classdef GUI_ReplayTool < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        wndPrerecorded                  matlab.ui.Figure
        lblPendingFF                    matlab.ui.control.Label
        btnJumpLongTime                 matlab.ui.control.Button
        chkImportGroundTruthTagsPrerec  matlab.ui.control.CheckBox
        cboTag                          matlab.ui.control.DropDown
        lblJumpToTag                    matlab.ui.control.Label
        lblPlayback                     matlab.ui.control.Label
        txtJump                         matlab.ui.control.EditField
        lblJumpTo                       matlab.ui.control.Label
        lblTime                         matlab.ui.control.Label
        btnPause                        matlab.ui.control.Button
    end

    
    properties (Access = private)
        recSess RecordingFunctions;
        windowIsInvalid = 0; % set to 1 to indicate that app is dead.
        maxTime;
        maxTimeText;
        fastFwdWarningPresented = 0;
    end
    
    methods (Access = public)
        function InvalidateAndExit(app)
            app.windowIsInvalid = 1;
            app.recSess = RecordingFunctions.empty();
            delete(app);
        end

        function UpdateReplayTool(app)
            if app.windowIsInvalid
                return
            end

            if app.recSess.RecordingStat == 0
                app.btnPause.Text = 'RESUME';
                app.btnPause.FontWeight = 'bold';
            else
                app.btnPause.Text = 'Pause';
                app.btnPause.FontWeight = 'normal'; 
            end
            
            % Get current time and maximum (prerecorded signal) time
            tNow = app.recSess.GetPrerecordedPlaybackTime();
            
            % Show time
            app.lblTime.Text = sprintf('%s / %s', ...
                datestr( seconds(tNow), 'HH:MM:SS.FFF' ), app.maxTimeText);
            
            % Show single-step fast forward
            if app.recSess.IsPrerecordedSingleStepPending()
                app.lblPendingFF.Visible = true;
                app.btnJumpLongTime.Visible = false;
            else
                app.lblPendingFF.Visible = false;
                app.btnJumpLongTime.Visible = true;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, recSess)
            if isempty(recSess) || ~strcmp(recSess.GetRecProps().Device, 'prerecorded')
                delete(app)
                error('Invalid recSess for ReplayTool GUI provided.')
            end
            app.recSess = recSess;
            
            % Put window on top
            set(0,'units','pixels');
            ScrInfo = get(0,'ScreenSize');
            ScrH = ScrInfo(4);
            CurPos = app.wndPrerecorded.Position;
            CurPos(2) = ScrH - CurPos(4) * 1.1;
            app.wndPrerecorded.Position = CurPos; 
            
            % Initialize
            currentRecProps = app.recSess.GetRecProps();
            sig = app.recSess.GetPrerecordedSignal();
            app.lblPlayback.Text = currentRecProps.originalFileName;
            app.chkImportGroundTruthTagsPrerec.Value = ...
                app.recSess.GetPrerecordedImportTrueTags();

            % Get maximum time (length of prerecorded signal)
            app.maxTime = app.recSess.GetPrerecordedSignalLength();
            app.maxTimeText = datestr( seconds(app.maxTime), 'HH:MM:SS.FFF' );

            % Populate list of tags
            nPrerecTags = sig.GetNumTags();
            app.cboTag.Items = {};
            app.cboTag.ItemsData = {};

            % Add first item as ' ' for sake of beauty
            items = cell(1, nPrerecTags + 1);
            itemsData = cell(1, nPrerecTags + 1);            
            items{1} = ' ';
            itemsData{1} = -1; % Negative value to ignore
            
            % Iterate over tags
            for nT = 1:nPrerecTags
                tag = sig.tags(1:2, nT);
                tagType = tag(1);
                tagTime = tag(2);
                items{nT + 1} = sprintf('Tag %d @ %s', tagType, datestr( seconds(tagTime), 'HH:MM:SS.FFF' ));
                itemsData{nT + 1} = tagTime;
            end

            app.cboTag.Items = items;
            app.cboTag.ItemsData = itemsData;
            app.cboTag.Value = -1;
        end

        % Close request function: wndPrerecorded
        function wndPrerecordedCloseRequest(app, event)
            if app.windowIsInvalid
                % Window is already dead. Just go futher with deletion.
                delete(app)
                return
            end
            
            if app.recSess.IsConnected
                if strcmp(questdlg('Closing this window will halt playback of your file.', 'Note', 'OK', 'Cancel', 'Cancel'), 'Cancel')
                    return
                end
            end

            % Stop recording on recSess. Then recSess will delete this
            % window automatically.
            % !@! Do not: delete(app)
            app.windowIsInvalid = 1;
            app.recSess.StopRecording();
        end

        % Button pushed function: btnPause
        function btnPauseButtonPushed(app, event)
            if app.windowIsInvalid
                return
            end
            
            app.recSess.RecordingStat = ~app.recSess.RecordingStat;
            app.UpdateReplayTool();
        end

        % Value changed function: txtJump
        function txtJumpValueChanged(app, event)
            if app.windowIsInvalid
                return
            end
            
            try
                Time = TimeStringToSeconds(app.txtJump.Value);
            catch
                % Invalid time
                app.txtJump.Value = '';
                app.lblJumpTo.FontColor = [1 0 0];
                return
            end

            app.txtJump.Value = '';
            app.lblJumpTo.FontColor = [0 0 0];

            % Pause and change playback time
            app.recSess.RecordingStat = 0;
            app.recSess.SetPrerecordedPlaybackTime(Time);
            app.UpdateReplayTool();
        end

        % Value changed function: cboTag
        function cboTagValueChanged(app, event)
            if app.windowIsInvalid
                return
            end

            % Note: app.cboTag.Value is already the item data not the text.
            time = app.cboTag.Value;
            if time < 0
                % Negative is ignored
                return
            end

            % Pause and change playback time
            app.recSess.RecordingStat = 0;
            app.recSess.SetPrerecordedPlaybackTime(time);
            app.UpdateReplayTool();
        end

        % Value changed function: chkImportGroundTruthTagsPrerec
        function chkImportGroundTruthTagsPrerecValueChanged(app, event)
            value = app.chkImportGroundTruthTagsPrerec.Value;
            app.recSess.SetPrerecordedImportTrueTags(value);
        end

        % Button pushed function: btnJumpLongTime
        function btnJumpLongTimeButtonPushed(app, event)
            if ~app.fastFwdWarningPresented
                % Give user a heads up.
                app.recSess.RecordingStat = 0;
                answer = questdlg(['A very long chunk of data will be ingested at once. ' ...
                    'Be advised that system might seem unresponsive for several seconds.'], ...
                    'Single Step Fast Forward', 'OK', 'Cancel', 'OK');
                if strcmp(answer, 'Cancel')
                    return
                end
                app.fastFwdWarningPresented = 1;
            end

            % Jump several minutes
            app.recSess.TriggerPrerecordedSingleStepJump(60 * 5);
            app.UpdateReplayTool();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.wndPrerecorded = uifigure('Visible', 'off');
            app.wndPrerecorded.Name = 'Prerecorded Signal';
            app.wndPrerecorded.CloseRequestFcn = createCallbackFcn(app, @wndPrerecordedCloseRequest, true);
            app.btnPause = uibutton(app.wndPrerecorded, 'push');
            app.btnPause.ButtonPushedFcn = createCallbackFcn(app, @btnPauseButtonPushed, true);
            app.btnPause.Text = 'Pause/Unpause';
            app.lblTime = uilabel(app.wndPrerecorded);
            app.lblTime.Text = '00:00:00.000 / 00.00.00.000';
            app.lblJumpTo = uilabel(app.wndPrerecorded);
            app.lblJumpTo.Text = {'Jump to time hh:mm:ss:'; ''};
            app.txtJump = uieditfield(app.wndPrerecorded, 'text');
            app.txtJump.ValueChangedFcn = createCallbackFcn(app, @txtJumpValueChanged, true);
            app.lblPlayback = uilabel(app.wndPrerecorded);
            app.lblPlayback.Text = 'Playback File';
            app.lblJumpToTag = uilabel(app.wndPrerecorded);
            app.lblJumpToTag.Text = 'or jump to tag:';
            app.cboTag = uidropdown(app.wndPrerecorded);
            app.cboTag.Items = {};
            app.cboTag.ValueChangedFcn = createCallbackFcn(app, @cboTagValueChanged, true);
            app.cboTag.Value = {};
            app.chkImportGroundTruthTagsPrerec = uicheckbox(app.wndPrerecorded);
            app.chkImportGroundTruthTagsPrerec.ValueChangedFcn = createCallbackFcn(app, @chkImportGroundTruthTagsPrerecValueChanged, true);
            app.chkImportGroundTruthTagsPrerec.Text = 'Import true tags from prerecorded';
            app.btnJumpLongTime = uibutton(app.wndPrerecorded, 'push');
            app.btnJumpLongTime.ButtonPushedFcn = createCallbackFcn(app, @btnJumpLongTimeButtonPushed, true);
            app.btnJumpLongTime.Text = '> > Fast Forward 5:00 minutes > >';
            app.lblPendingFF = uilabel(app.wndPrerecorded);
            app.lblPendingFF.Text = '~~ Pending. see log window ~~';
            % Show the figure after all components are created
            app.wndPrerecorded.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_ReplayTool(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.wndPrerecorded)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.wndPrerecorded)
        end
    end
end