classdef LoadSignal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        Menu          matlab.ui.container.Menu
        LoadMenu      matlab.ui.container.Menu
        Panel         matlab.ui.container.Panel
        SelectButton  matlab.ui.control.Button
        Tree          matlab.ui.container.CheckBoxTree
        EMGNode       matlab.ui.container.TreeNode
        Node2         matlab.ui.container.TreeNode
        Node3         matlab.ui.container.TreeNode
        Node4         matlab.ui.container.TreeNode
        Node5         matlab.ui.container.TreeNode
        IMUNode       matlab.ui.container.TreeNode
        Node2_2       matlab.ui.container.TreeNode
        Node3_2       matlab.ui.container.TreeNode
        Node4_2       matlab.ui.container.TreeNode
        Node5_2       matlab.ui.container.TreeNode
        PSNode        matlab.ui.container.TreeNode
        Node2_3       matlab.ui.container.TreeNode
        Node3_3       matlab.ui.container.TreeNode
        Node4_3       matlab.ui.container.TreeNode
        Node5_3       matlab.ui.container.TreeNode
        TagsNode      matlab.ui.container.TreeNode
        Node2_4       matlab.ui.container.TreeNode
        Node3_4       matlab.ui.container.TreeNode
        Node4_4       matlab.ui.container.TreeNode
        Node5_4       matlab.ui.container.TreeNode
        EditField     matlab.ui.control.EditField
        Button_4      matlab.ui.control.Button
        Button_3      matlab.ui.control.Button
        Button_2      matlab.ui.control.Button
        Button        matlab.ui.control.Button
        UIAxes        matlab.ui.control.UIAxes
    end


    properties (Access = public)
        recProps RecordingProperties
        sigData Signal;
        processing Decoding;
        onlineRecSess RecordingFunctions;
    end
    properties (Access = private)
        WindowSize=2;

        StepSize=1;
        CurrentPointer;  % last sample to draw
        PrevPointer;
    end
    properties (Dependent, Access = private)
        % Example of propert getter
        numAllCh
        numEMGCh
        numIMUCh
        sf
        % TODO: See get.numCh and get.sf below.
        % TODO: Add more.
    end

    methods (Access = public)

        function  PlotLoadedData(app)
            nCh=app.recProps.NumAllCh;
            FS = app.recProps.SamplingFreq;
            sigLen = length(app.sigData.signal);

            % Verify app.CurrentPointer
            if app.CurrentPointer < app.WindowSize
                % Don't allow going before zero.
                app.CurrentPointer = app.WindowSize;
            end
            if app.CurrentPointer > sigLen
                % Don't allow going after end of signal.
                app.CurrentPointer = sigLen;
            end
            
            % Get sample range
            rangeStart = app.CurrentPointer - app.WindowSize + 1;
            if rangeStart < 1
                rangeStart = 1;
            end
            rangeEnd = rangeStart + app.WindowSize - 1;
            if rangeEnd > sigLen
                rangeEnd = sigLen;
            end
            range = rangeStart : rangeEnd;

            app.EditField.Value = num2str( (rangeStart - 1) / FS );

            % Params
            yHeightCh = 10; % units
            %Idx Acc
            idxacc=[];
            
            accstart=app.recProps.NumEMGCh+1;
            numImuSenosr= length(app.recProps.IdxIMU)/6;
            for l = accstart:app.recProps.IdxIMU(end)-1
                for numimu=0:numImuSenosr-1
                    if app.recProps.IdxIMU(l-accstart+1)== (accstart+6*numimu+1) || app.recProps.IdxIMU(l-accstart+1)== (accstart+6*numimu+2) || app.recProps.IdxIMU(l-accstart+1)== (accstart+6*numimu+3)
                        idxacc=[idxacc,app.recProps.IdxIMU(l-accstart+1)];
                    end
                end
            end


            %
            tRange = range / FS;


            % We assume yHeightCh vertical units size for every row (channel),
            %   however we give more swing range for each channel.
            % Rational is that channels will touch the extremes less
            %   often, so let's use that space.
            yExtraSwingCh = 0.6 * yHeightCh;
            yTotalSwingCh = yExtraSwingCh + yHeightCh;
            yHeight = yHeightCh * nCh;

            % Find swing center point for each channel
            yOffset = yHeightCh * ((1 : nCh) - 0.5);
            yOffset=double(yOffset);


            %   This is used for normalization.

            nNorm = range;

            % Find normalization factor for various signal subgroups.
            epsilon = 1e-8;
            sigRangeEMG = 0.5 * yTotalSwingCh / max(epsilon, max(abs(app.sigData.signal(app.recProps.IdxEMG, nNorm)), [], 'all'));
            if app.recProps.HasIMU
                sigRangeIMU = 0.5 * yTotalSwingCh / max(epsilon, max(abs(app.sigData.signal(app.recProps.IdxIMU, nNorm)), [], 'all'));
                sigRangeAcc= 0.5 * yTotalSwingCh / max(epsilon, max(abs(app.sigData.signal(idxacc, nNorm))/2, [], 'all'));
            end
            if app.recProps.HasPS
                % PS is looks like a square wave. Aesthetically better
                % not to use the extra swing range.
                sigRangePS =  0.5 * yTotalSwingCh;% /20; %max(epsilon, max(abs(rs.signal(app.recProps.IdxPS, tNorm)), [], 'all'));
            end


            % Get type of all channels
            typeOfCh = app.recProps.GetChannelTypes();

            % Draw tag lines
            tagCount=0;
            taginRangeTimes = [];

            if ~isempty(app.sigData.tags)

                tagTimes = app.sigData.tags(2, :);
                for i=1:length(tagTimes)
                    if tagTimes(i)*FS>range(1)&& tagTimes(i)*FS<range(end)
                        tagCount=tagCount+1;
                        taginRange(:,tagCount)=app.sigData.tags(:, i);
                    end
                end
                if tagCount>0
                    taginRangeTimes= taginRange(2,:);
                    taginRangeName=  taginRange(1,:);
                end
            end

            %hold(app.UIAxes, 'off');
            cla(app.UIAxes);

            for k=1:length(taginRangeTimes)
                xline(app.UIAxes,(taginRangeTimes(k)))
                [txt,~] = GetTagName(taginRangeName(k));
                text(app.UIAxes,(taginRangeTimes(k)),(yHeight + yExtraSwingCh+4),txt)
                hold(app.UIAxes, 'on');

            end

            app.SelectButton.Text = sprintf("%d", length(taginRangeTimes));


            % Draw all channels
            % CAUTION: Do not iterate over 1:nCh. There might be gaps.
            for c = app.recProps.IdxAll
                if typeOfCh(c) == 1
                    yRange = sigRangePS;
                    y = -(app.sigData.signal(c, range)-8.5) * yRange + yOffset(c);
                elseif typeOfCh(c) == 2
                    for m=idxacc(1):idxacc(end)
                        if c==m
                             yRange = sigRangeAcc;
                        else
                            yRange = sigRangeIMU;
                        end

                    end
                    y = app.sigData.signal(c, range) * yRange + yOffset(c);
                elseif typeOfCh(c) == 0
                    yRange = sigRangeEMG;
                    y = app.sigData.signal(c, range) * yRange + yOffset(c);
                end

                plot(app.UIAxes, tRange, y);

                hold(app.UIAxes, 'on');
            end


            % Set graph limits  
            app.UIAxes.YLim = [-10, yHeight + yExtraSwingCh];

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: LoadMenu
        function LoadMenuSelected(app, event)

            app.sigData = Signal.LoadSignal();
            app.recProps=app.sigData.recProps;
            app.WindowSize=7*(app.recProps.SamplingFreq);
            app.StepSize=str2num(app.EditField.Value)*(app.recProps.SamplingFreq);

            app.CurrentPointer=app.WindowSize;
            app.PlotLoadedData();


            %Plot Signal


        end

        % Button pushed function: Button
        function ButtonPushed(app, event)

            app.CurrentPointer=app.CurrentPointer+app.StepSize;
            app.PlotLoadedData();

        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)

            app.CurrentPointer=app.CurrentPointer-app.StepSize;
            app.PlotLoadedData();


        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)

            app.CurrentPointer=length(app.sigData.signal);
            app.PlotLoadedData();

        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)

            app.CurrentPointer=app.WindowSize;
            app.PlotLoadedData();

        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)

            app.StepSize=str2num(app.EditField.Value)*(app.recProps.SamplingFreq);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.LoadMenu = uimenu(app.Menu);
            app.LoadMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadMenuSelected, true);
            app.LoadMenu.Text = 'Load';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Data')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '>';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '<';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '<<';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '>>';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Value = '1';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.Tree = uitree(app.Panel, 'checkbox');
            app.EMGNode = uitreenode(app.Tree);
            app.EMGNode.Text = 'EMG';
            app.Node2 = uitreenode(app.EMGNode);
            app.Node2.Text = 'Node2';
            app.Node3 = uitreenode(app.EMGNode);
            app.Node3.Text = 'Node3';
            app.Node4 = uitreenode(app.EMGNode);
            app.Node4.Text = 'Node4';
            app.Node5 = uitreenode(app.EMGNode);
            app.Node5.Text = 'Node5';
            app.IMUNode = uitreenode(app.Tree);
            app.IMUNode.Text = 'IMU';
            app.Node2_2 = uitreenode(app.IMUNode);
            app.Node2_2.Text = 'Node2';
            app.Node3_2 = uitreenode(app.IMUNode);
            app.Node3_2.Text = 'Node3';
            app.Node4_2 = uitreenode(app.IMUNode);
            app.Node4_2.Text = 'Node4';
            app.Node5_2 = uitreenode(app.IMUNode);
            app.Node5_2.Text = 'Node5';
            app.PSNode = uitreenode(app.Tree);
            app.PSNode.Text = 'PS';
            app.Node2_3 = uitreenode(app.PSNode);
            app.Node2_3.Text = 'Node2';
            app.Node3_3 = uitreenode(app.PSNode);
            app.Node3_3.Text = 'Node3';
            app.Node4_3 = uitreenode(app.PSNode);
            app.Node4_3.Text = 'Node4';
            app.Node5_3 = uitreenode(app.PSNode);
            app.Node5_3.Text = 'Node5';
            app.TagsNode = uitreenode(app.Tree);
            app.TagsNode.Text = 'Tags';
            app.Node2_4 = uitreenode(app.TagsNode);
            app.Node2_4.Text = 'Node2';
            app.Node3_4 = uitreenode(app.TagsNode);
            app.Node3_4.Text = 'Node3';
            app.Node4_4 = uitreenode(app.TagsNode);
            app.Node4_4.Text = 'Node4';
            app.Node5_4 = uitreenode(app.TagsNode);
            app.Node5_4.Text = 'Node5';
            app.SelectButton = uibutton(app.Panel, 'push');
            app.SelectButton.Text = 'Select';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LoadSignal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end