classdef GUI_OSL < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        StandButton  matlab.ui.control.Button
        WalkButton   matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Represents buttons

            rpi = raspi('192.168.142.159','pi','hello'); %Creates raspi object

            %Opens can
            system(rpi,'/sbin/ip link set can0 up type can bitrate 1000000','sudo');

            %%
            %Starts motors
            system(rpi,'cansend can0 001#fffffffffffffffc','sudo'); %Port 1
            system(rpi,'cansend can0 002#fffffffffffffffc','sudo'); %Port 2

            %To execute commands on rpi

            %I put the mode as a variable to true this so you can easily find it and
            %put it in the GUI
        end

        % Button pushed function: WalkButton
        function WalkButtonPushed(app, event)
system(rpi,'python /home/pi/Delete_approach/SM_offline.py','sudo'); %Executes SM for walking mode
        end

        % Button pushed function: StandButton
        function StandButtonPushed(app, event)
system(rpi,'python /home/pi/Delete_approach/SM_offline_stiff.py','sudo'); %Executes SM for stiff mode
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            switch key
                case key == 'numpad2'

                    system(rpi,'python /home/pi/Delete_approach/SM_offline.py','sudo'); %Executes SM for walking mode

                case key == 'numpad3'
                    system(rpi,'python /home/pi/Delete_approach/SM_offline_stiff.py','sudo'); %Executes SM for stiff mode

            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.WalkButton = uibutton(app.UIFigure, 'push');
            app.WalkButton.ButtonPushedFcn = createCallbackFcn(app, @WalkButtonPushed, true);
            app.WalkButton.Text = 'Walk';
            app.StandButton = uibutton(app.UIFigure, 'push');
            app.StandButton.ButtonPushedFcn = createCallbackFcn(app, @StandButtonPushed, true);
            app.StandButton.Text = 'Stand';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_OSL

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end